classdef MatrixCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatrixAppUIFigure              matlab.ui.Figure
        HomePanel                      matlab.ui.container.Panel
        HomeDeveloperButton            matlab.ui.control.Image
        HomeNextButton                 matlab.ui.control.Image
        HomeImage                      matlab.ui.control.Image
        OptionsPanel                   matlab.ui.container.Panel
        OptionsMatrixArithButton       matlab.ui.control.Image
        OptionsHomeButton              matlab.ui.control.Image
        OptionsNextButton              matlab.ui.control.Image
        OptionsImage                   matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        ArithmeticTab                  matlab.ui.container.Tab
        MatrixCalculatorPanel          matlab.ui.container.Panel
        MatrixFunctionsTab             matlab.ui.container.Tab
        MatrixFunctionsPanel           matlab.ui.container.Panel
        InverseButton                  matlab.ui.control.Button
        DeterminantButton              matlab.ui.control.Button
        TransposeButton                matlab.ui.control.Button
        ClearResButton_2               matlab.ui.control.Button
        ClearAllButton_2               matlab.ui.control.Button
        ResultPanel_2                  matlab.ui.container.Panel
        Matrix1Label_2                 matlab.ui.control.Label
        numColSpinner_2                matlab.ui.control.Spinner
        NumberofColumnsSpinnerLabel_3  matlab.ui.control.Label
        numRowSpinner_2                matlab.ui.control.Spinner
        NumberofRowsSpinnerLabel_3     matlab.ui.control.Label
        DisplayButton_2                matlab.ui.control.Button
        DisplayPanel1_2                matlab.ui.container.Panel
        MatrixArithmeticPanel          matlab.ui.container.Panel
        ClearAllButton                 matlab.ui.control.Button
        MULLamp                        matlab.ui.control.Lamp
        SUBLamp                        matlab.ui.control.Lamp
        ADDLamp                        matlab.ui.control.Lamp
        MULSwitchLabel                 matlab.ui.control.Label
        SUBLabel                       matlab.ui.control.Label
        ADDSwitchLabel                 matlab.ui.control.Label
        ClearResButton                 matlab.ui.control.Button
        MULSwitch                      matlab.ui.control.ToggleSwitch
        SUBSwitch                      matlab.ui.control.ToggleSwitch
        ADDSwitch                      matlab.ui.control.ToggleSwitch
        CalculateButton                matlab.ui.control.Button
        DisplayButton                  matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        numColSpinner2                 matlab.ui.control.Spinner
        NumberofColumnsSpinnerLabel_2  matlab.ui.control.Label
        numRowSpinner2                 matlab.ui.control.Spinner
        NumberofRowsSpinnerLabel_2     matlab.ui.control.Label
        numColSpinner                  matlab.ui.control.Spinner
        NumberofColumnsSpinnerLabel    matlab.ui.control.Label
        numRowSpinner                  matlab.ui.control.Spinner
        NumberofRowsSpinnerLabel       matlab.ui.control.Label
        DisplayPanel2                  matlab.ui.container.Panel
        DisplayPanel1                  matlab.ui.container.Panel
        MatrixArithmeticImage          matlab.ui.control.Image
    end

    
    properties (Access = private)
        numRowSp1
        numColSp1
        numRowSp2
        numColSp2
        numRowSp3
        numColSp3
        children1
        children2
        children3
        operation
        matrixA
        matrixB
        matrixC
        resRow
        resCol
        resRow_2
        resCol_2
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numRowSp1 = 1;
            app.numColSp1 = 1;
            app.numRowSp2 = 1;
            app.numColSp2 = 1;
            app.numRowSp3 = 1;
            app.numColSp3 = 1;
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65'; % gray
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';

            % Home Panel Positioning
            app.HomePanel.Position = [1,-1,1037,690];
        end

        % Image clicked function: HomeNextButton
        function HomeNextButtonImageClicked(app, event)
            app.OptionsPanel.Visible = "on";
            app.OptionsPanel.Position = [1,-1,1037,690];
            app.HomePanel.Visible = "off";
        end

        % Image clicked function: HomeDeveloperButton
        function HomeDeveloperButtonImageClicked(app, event)
            app.OptionsPanel.Visible = "on";
            app.OptionsPanel.Position = [1,-1,1037,690];
            app.HomePanel.Visible = "off";
        end

        % Image clicked function: OptionsHomeButton
        function OptionsHomeButtonImageClicked(app, event)
            app.HomePanel.Visible = "on";
            app.OptionsPanel.Visible = "off";
            app.HomePanel.Position = [1,-1,1037,690];
        end

        % Image clicked function: OptionsMatrixArithButton
        function OptionsMatrixArithButtonImageClicked(app, event)
            
        end

        % Value changing function: numRowSpinner
        function numRowSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.numRowSp1 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numColSpinner
        function numColSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.numColSp1 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numRowSpinner2
        function numRowSpinner2ValueChanging(app, event)
            changingValue = event.Value;
            app.numRowSp2 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numColSpinner2
        function numColSpinner2ValueChanging(app, event)
            changingValue = event.Value;
            app.numColSp2 = changingValue;
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end
            
            % Matrix 1
            paddingX = 10;
            paddingY = 10;

            numRows1 = app.numRowSp1;
            numCols1 = app.numColSp1;
            
            panelWidth1 = app.DisplayPanel1.Position(3) - 30;
            panelHeight1 = app.DisplayPanel1.Position(4) - 30;

            fieldWidth1 = (panelWidth1 - (paddingX * (numCols1 - 1))) / numCols1;
            fieldHeight1 = (panelHeight1 - (paddingY * (numRows1 - 1))) / numRows1;
 
            for row1 = 1:numRows1
                for col1 = 1:numCols1
                    field = uieditfield(app.DisplayPanel1,'numeric', 'Editable', 'on', 'HorizontalAlignment', 'center');

                    fieldX1 = ((col1 - 1) * (fieldWidth1 + paddingX)) + 15;
                    fieldY1 = (panelHeight1 - row1 * (fieldHeight1 + paddingY)) + 25;
                    
                    field.Tag = sprintf('A%d%d', row1, col1);
                    field.Position = [fieldX1, fieldY1, fieldWidth1, fieldHeight1];
                    pause(0.05)
                end
            end

            % Matrix 2
            numRows2 = app.numRowSp2;
            numCols2 = app.numColSp2;

            panelWidth2 = app.DisplayPanel2.Position(3) - 30;
            panelHeight2 = app.DisplayPanel2.Position(4) - 30;

            fieldWidth2 = (panelWidth2 - (paddingX * (numCols2 - 1))) / numCols2;
            fieldHeight2 = (panelHeight2 - (paddingY * (numRows2 - 1))) / numRows2;
 
            for row2 = 1:numRows2
                for col2 = 1:numCols2
                    field = uieditfield(app.DisplayPanel2,'numeric', 'Editable', 'on', 'HorizontalAlignment', 'center');

                    fieldX2 = ((col2 - 1) * (fieldWidth2 + paddingX)) + 15;
                    fieldY2 = (panelHeight2 - row2 * (fieldHeight2 + paddingY)) + 25;
                    
                    field.Tag = sprintf('B%d%d', row2, col2);
                    field.Position = [fieldX2, fieldY2, fieldWidth2, fieldHeight2];
                    pause(.05)
                end
            end

            % Matrix 1 Children Tagging
            app.children1 = app.DisplayPanel1.Children;

            for idx = 1:length(app.children1)
                childIdx1 = app.children1(idx).Tag;
                disp(['The tag of Matrix 1 component ' num2str(idx) ' is: ' childIdx1]);
            end

            % Matrix 2 Children Tagging
            app.children2 = app.DisplayPanel2.Children;

            for idx = 1:length(app.children2)
                childIdx2 = app.children2(idx).Tag;
                disp(['The tag of Matrix 2 component ' num2str(idx) ' is: ' childIdx2]);
            end

            % Clear All Button
            app.ClearAllButton.Enable = 'on';

            %Lamp
            if (app.numRowSp1 == app.numRowSp2) && (app.numColSp1 == app.numColSp2)
                app.ADDLamp.Color = 'g';
                app.SUBLamp.Color = 'g';
                app.ADDSwitch.Enable = "on";
                app.SUBSwitch.Enable = "on";
            else
                app.ADDLamp.Color = 'r';
                app.SUBLamp.Color = 'r';
                app.ADDSwitch.Value = 'Off';
                app.SUBSwitch.Value = 'Off';
            end
            
            if (app.numColSp1 == app.numRowSp2 || app.numColSp1 == 1 && app.numRowSp1 == 1 || app.numColSp2 == 1 && app.numRowSp2 == 1)
                app.MULLamp.Color = 'g';
                app.MULSwitch.Enable = "on";
            else
                app.MULLamp.Color = 'r';
                app.MULSwitch.Value = 'Off';
            end

            % Calculate Button
            if (strcmp(app.MULSwitch.Value, 'Off')) && (strcmp(app.SUBSwitch.Value, 'Off') && strcmp(app.ADDSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            else
                app.CalculateButton.Enable = "on";
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Matrix 1
            matrix1 = zeros(app.numRowSp1, app.numColSp1);
            
            for row1 = 1:app.numRowSp1
                for col1 = 1:app.numColSp1
                    idx1 = sprintf('A%d%d', row1, col1);
                    uiComponent1 = findobj(app.MatrixAppUIFigure, 'Tag', idx1);
                    currentValue1 = uiComponent1.Value;
                    matrix1(row1, col1) = currentValue1;
                end
            end

            app.matrixA = matrix1;

            % Matrix 2
            matrix2 = zeros(app.numRowSp2, app.numColSp2);

            for row2 = 1:app.numRowSp2
                for col2 = 1:app.numColSp2
                    idx2 = sprintf('B%d%d', row2, col2);
                    uiComponent2 = findobj(app.MatrixAppUIFigure, 'Tag', idx2);
                    currentValue2 = uiComponent2.Value;
                    matrix2(row2, col2) = currentValue2;
                end
            end

            app.matrixB = matrix2;
            
            % for cli checking
            disp("Matrix A")
            disp(app.matrixA)
            disp("Matrix B")
            disp(app.matrixB)


            % Matrices Calculation
            if strcmp(app.ADDSwitch.Value, 'On')
                    if (app.numRowSp1 == app.numRowSp2) && (app.numColSp1 == app.numColSp2)
                        sumOfMatrices = app.matrixA + app.matrixB;
                        resultMatrix = sumOfMatrices;
                        [app.resRow, app.resCol] = size(sumOfMatrices);
                    else
                        msgbox('Arrays have incompatible sizes for this operation.', 'Error: Incompatible Sizes')
                        app.ADDSwitch.Value = 'Off';
                        app.SUBSwitch.Value = 'Off';
                        app.MULSwitch.Value = 'Off';
                        app.CalculateButton = 'Off';
                    end
            elseif strcmp(app.SUBSwitch.Value, 'On')
                    if (app.numRowSp1 == app.numRowSp2) && (app.numColSp1 == app.numColSp2)
                        difOfMatrices = app.matrixA - app.matrixB;
                        resultMatrix = difOfMatrices;
                        [app.resRow, app.resCol] = size(difOfMatrices);
                    else
                        msgbox('Arrays have incompatible sizes for this operation.', 'Error: Incompatible Sizes')
                        app.ADDSwitch.Value = 'Off';
                        app.SUBSwitch.Value = 'Off';
                        app.MULSwitch.Value = 'Off';
                        app.CalculateButton = 'Off';
                    end
            elseif strcmp(app.MULSwitch.Value, 'On')
                    if (app.numColSp1 == app.numRowSp2 || app.numColSp1 == 1 && app.numRowSp1 == 1 || app.numColSp2 == 1 && app.numRowSp2 == 1)
                        prodOfMatrices = app.matrixA * app.matrixB;
                        resultMatrix = prodOfMatrices;
                        [app.resRow, app.resCol] = size(prodOfMatrices);
                    end
            else
                msgbox('No Operation Selected. Please Select an Operation.', 'Error: No Operation Detected.')
                app.CalculateButton.Enable = "off";
            end

            % displaying the result
            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end
            
            paddingX = 10;
            paddingY = 10;

            numRows3 = app.resRow;
            numCols3 = app.resCol;
            
            panelWidth3 = app.Panel.Position(3) - 30;
            panelHeight3 = app.Panel.Position(4) - 30;

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row3 = 1:numRows3
                for col3 = 1:numCols3
                    field = uieditfield(app.Panel,'numeric', 'Editable', 'off', 'HorizontalAlignment', 'center', 'Value', resultMatrix(row3, col3));

                    fieldX3 = ((col3 - 1) * (fieldWidth3 + paddingX)) + 15;
                    fieldY3 = (panelHeight3 - row3 * (fieldHeight3 + paddingY)) + 25;

                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                end
            end

            % Clear All Button
            app.ClearResButton.Enable = "on";
            app.ClearAllButton.Enable = 'on';
        end

        % Value changed function: ADDSwitch
        function ADDSwitchValueChanged(app, event)
            value = app.ADDSwitch.Value;
            if strcmp(value, 'On')
               app.CalculateButton.Enable = "on";
               if ~isempty(app.Panel)
                    delete(app.Panel.Children)
               end
            end

            if (strcmp(app.SUBSwitch.Value, 'On') || strcmp(app.MULSwitch.Value, 'On')) && strcmp(value, 'On') && (~isempty(app.DisplayPanel1) || ~isempty(app.DisplayPanel2))
                app.SUBSwitch.Value = 'Off';
                app.MULSwitch.Value = 'Off';
                app.CalculateButton.Enable = "on";
            end

            if (strcmp(value, 'Off')) && (strcmp(app.SUBSwitch.Value, 'Off') && strcmp(app.MULSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            end
        end

        % Value changed function: MULSwitch
        function MULSwitchValueChanged(app, event)
            value = app.MULSwitch.Value;
            if strcmp(value, 'On')
               app.CalculateButton.Enable = "on";
               if ~isempty(app.Panel)
                    delete(app.Panel.Children)
               end
            end

            if (strcmp(app.SUBSwitch.Value, 'On') || strcmp(app.ADDSwitch.Value, 'On')) && strcmp(value, 'On') && (~isempty(app.DisplayPanel1) || ~isempty(app.DisplayPanel2))
                app.SUBSwitch.Value = 'Off';
                app.ADDSwitch.Value = 'Off';
                app.CalculateButton.Enable = "on";
            end

            if (strcmp(value, 'Off')) && (strcmp(app.SUBSwitch.Value, 'Off') && strcmp(app.ADDSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            end
        end

        % Value changed function: SUBSwitch
        function SUBSwitchValueChanged(app, event)
            value = app.SUBSwitch.Value;
            if strcmp(value, 'On')
               app.CalculateButton.Enable = "on";
               if ~isempty(app.Panel)
                    delete(app.Panel.Children)
               end
            end

            if (strcmp(app.MULSwitch.Value, 'On') || strcmp(app.ADDSwitch.Value, 'On')) && strcmp(value, 'On') && (~isempty(app.DisplayPanel1) || ~isempty(app.DisplayPanel2))
                app.MULSwitch.Value = 'Off';
                app.ADDSwitch.Value = 'Off';
                app.CalculateButton.Enable = "on";
            end

            if (strcmp(value, 'Off')) && (strcmp(app.MULSwitch.Value, 'Off') && strcmp(app.ADDSwitch.Value, 'Off'))
                app.CalculateButton.Enable = "off";
            end
        end

        % Button pushed function: ClearResButton
        function ClearResButtonPushed(app, event)
            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end
            
            app.CalculateButton.Enable = "off";
            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.ClearResButton.Enable = "off";
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            if ~isempty(app.DisplayPanel1)
                delete(app.DisplayPanel1.Children)
            end

            if ~isempty(app.DisplayPanel2)
                delete(app.DisplayPanel2.Children)
            end

            if ~isempty(app.Panel)
                delete(app.Panel.Children)
            end

            app.SUBSwitch.Value = 'Off';
            app.ADDSwitch.Value = 'Off';
            app.MULSwitch.Value = 'Off';
            app.CalculateButton.Enable = "off";
            app.ADDLamp.Color = '0.65,0.65,0.65';
            app.SUBLamp.Color = '0.65,0.65,0.65';
            app.MULLamp.Color = '0.65,0.65,0.65';
            app.ADDSwitch.Enable = "off";
            app.SUBSwitch.Enable = "off";
            app.MULSwitch.Enable = "off";
            app.ClearResButton.Enable = "off";
            app.ClearAllButton.Enable = 'off';
        end

        % Value changing function: numRowSpinner_2
        function numRowSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            app.numRowSp3 = changingValue;
            if ~isempty(app.DisplayPanel1_2)
                delete(app.DisplayPanel1_2.Children)
            end

            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            app.TransposeButton.Enable = "off";
            app.DeterminantButton.Enable = "off";
            app.InverseButton.Enable = "off";
            app.ClearResButton_2.Enable = "off";
            app.ClearAllButton_2.Enable = 'off';
        end

        % Value changing function: numColSpinner_2
        function numColSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            app.numColSp3 = changingValue;
            if ~isempty(app.DisplayPanel1_2)
                delete(app.DisplayPanel1_2.Children)
            end

            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            app.TransposeButton.Enable = "off";
            app.DeterminantButton.Enable = "off";
            app.InverseButton.Enable = "off";
            app.ClearResButton_2.Enable = "off";
            app.ClearAllButton_2.Enable = 'off';
        end

        % Button pushed function: DisplayButton_2
        function DisplayButton_2Pushed(app, event)
            if ~isempty(app.DisplayPanel1_2)
                delete(app.DisplayPanel1_2.Children)
            end
            
            % Matrix 3
            paddingX = 10;
            paddingY = 10;

            numRows3 = app.numRowSp3;
            numCols3 = app.numColSp3;
            
            panelWidth3 = app.DisplayPanel1_2.Position(3) - 30;
            panelHeight3 = app.DisplayPanel1_2.Position(4) - 30;

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row1 = 1:numRows3
                for col1 = 1:numCols3
                    field = uieditfield(app.DisplayPanel1_2,'numeric', 'Editable', 'on', 'HorizontalAlignment', 'center');

                    fieldX3 = ((col1 - 1) * (fieldWidth3 + paddingX)) + 15;
                    fieldY3 = (panelHeight3 - row1 * (fieldHeight3 + paddingY)) + 25;
                    
                    field.Tag = sprintf('A%d%d', row1, col1);
                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                    pause(0.05)
                end
            end

            % Matrix 3 Children Tagging
            app.children3 = app.DisplayPanel1_2.Children;

            for idx = 1:length(app.children3)
                childIdx3 = app.children3(idx).Tag;
                disp(['The tag of Matrix 1 component ' num2str(idx) ' is: ' childIdx3]);
            end

            app.children2 = app.DisplayPanel2.Children;

            % Button Interactions
            app.TransposeButton.Enable = "on";
            if app.numRowSp3 == app.numColSp3
                app.DeterminantButton.Enable = "on";
                app.InverseButton.Enable = "on";
            end
            app.ClearAllButton_2.Enable = 'on';
        end

        % Button pushed function: TransposeButton
        function TransposeButtonPushed(app, event)
            % Matrix 3
            matrix3 = zeros(app.numRowSp3, app.numColSp3);

            for row3 = 1:app.numRowSp3
                for col3 = 1:app.numColSp3
                    idx3 = sprintf('A%d%d', row3, col3);
                    uiComponent3 = findobj(app.MatrixAppUIFigure, 'Tag', idx3);
                    currentValue3 = uiComponent3.Value;
                    matrix3(row3, col3) = currentValue3;
                end
            end

            app.matrixC = matrix3;

            % for cli checking
            disp("Matrix C")
            disp(app.matrixC)

            % Transposing
            app.matrixC = matrix3';
            resultMatrix_2 = app.matrixC;
            [app.resRow_2, app.resCol_2] = size(app.matrixC);

            % for cli checking
            disp("Transposed Matrix C")
            disp(app.matrixC)

            % displaying the result
            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            paddingX = 10;
            paddingY = 10;

            numRows3 = app.resRow_2;
            numCols3 = app.resCol_2;
            
            panelWidth3 = app.ResultPanel_2.Position(3) - 30;
            panelHeight3 = app.ResultPanel_2.Position(4) - 30;

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row3 = 1:numRows3
                for col3 = 1:numCols3
                    field = uieditfield(app.ResultPanel_2,'numeric', 'Editable', 'off', 'HorizontalAlignment', 'center', 'Value', resultMatrix_2(row3, col3));

                    fieldX3 = ((col3 - 1) * (fieldWidth3 + paddingX)) + 15;
                    fieldY3 = (panelHeight3 - row3 * (fieldHeight3 + paddingY)) + 25;
                    
                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                end
            end

            % Button Interactions
            app.ClearResButton_2.Enable = "on";
            app.ClearAllButton_2.Enable = 'on';
        end

        % Button pushed function: DeterminantButton
        function DeterminantButtonPushed(app, event)
            % Matrix 3
            matrix3 = zeros(app.numRowSp3, app.numColSp3);

            for row3 = 1:app.numRowSp3
                for col3 = 1:app.numColSp3
                    idx3 = sprintf('A%d%d', row3, col3);
                    uiComponent3 = findobj(app.MatrixAppUIFigure, 'Tag', idx3);
                    currentValue3 = uiComponent3.Value;
                    matrix3(row3, col3) = currentValue3;
                end
            end

            app.matrixC = matrix3;

            % for cli checking
            disp("Matrix C")
            disp(app.matrixC)

            % Determinant
            app.matrixC = round(det(matrix3));
            resultMatrix_2 = app.matrixC;
            [app.resRow_2, app.resCol_2] = size(app.matrixC);

            % for cli checking
            disp("Determinant of Matrix C")
            disp(app.matrixC)

            % displaying the result
            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            paddingX = 10;
            paddingY = 10;

            numRows3 = app.resRow_2;
            numCols3 = app.resCol_2;
            
            panelWidth3 = app.ResultPanel_2.Position(3) - 30;
            panelHeight3 = app.ResultPanel_2.Position(4) - 30;

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row3 = 1:numRows3
                for col3 = 1:numCols3
                    field = uieditfield(app.ResultPanel_2,'numeric', 'Editable', 'off', 'HorizontalAlignment', 'center', 'Value', resultMatrix_2(row3, col3));

                    fieldX3 = (col3 - 1) * (fieldWidth3 + paddingX);
                    fieldY3 = panelHeight3 - row3 * (fieldHeight3 + paddingY);

                    if row3 == 1
                        fieldY3 = panelHeight3 - row3 * fieldHeight3;
                    end
                    
                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                end
            end

            % Button Interactions
            app.ClearResButton_2.Enable = "on";
            app.ClearAllButton_2.Enable = 'on';
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            % Matrix 3
            matrix3 = zeros(app.numRowSp3, app.numColSp3);

            for row3 = 1:app.numRowSp3
                for col3 = 1:app.numColSp3
                    idx3 = sprintf('A%d%d', row3, col3);
                    uiComponent3 = findobj(app.MatrixAppUIFigure, 'Tag', idx3);
                    currentValue3 = uiComponent3.Value;
                    matrix3(row3, col3) = currentValue3;
                end
            end

            app.matrixC = matrix3;

            % for cli checking
            disp("Matrix C")
            disp(app.matrixC)

            % Inversing
            if round(det(matrix3)) == 0
                if ~isempty(app.ResultPanel_2)
                    delete(app.ResultPanel_2.Children)
                end
                msgbox("Warning: The matrix is singular thus the value of determinants is zero and the matrix of the inverse does not exist.", "Error")
                return;
            end
            app.matrixC = inv(matrix3);
            resultMatrix_2 = app.matrixC;
            [app.resRow_2, app.resCol_2] = size(app.matrixC);

            % for cli checking
            disp("Determinant of Matrix C")
            disp(app.matrixC)

            % displaying the result
            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            paddingX = 5;
            paddingY = 5;

            numRows3 = app.resRow_2;
            numCols3 = app.resCol_2;
            
            panelWidth3 = app.ResultPanel_2.Position(3);
            panelHeight3 = app.ResultPanel_2.Position(4);

            fieldWidth3 = (panelWidth3 - (paddingX * (numCols3 - 1))) / numCols3;
            fieldHeight3 = (panelHeight3 - (paddingY * (numRows3 - 1))) / numRows3;
 
            for row3 = 1:numRows3
                for col3 = 1:numCols3
                    field = uieditfield(app.ResultPanel_2,'numeric', 'Editable', 'off', 'HorizontalAlignment', 'center', 'Value', resultMatrix_2(row3, col3));

                    fieldX3 = (col3 - 1) * (fieldWidth3 + paddingX);
                    fieldY3 = panelHeight3 - row3 * (fieldHeight3 + paddingY);

                    if row3 == 1
                        fieldY3 = panelHeight3 - row3 * fieldHeight3;
                    end
                    
                    field.Position = [fieldX3, fieldY3, fieldWidth3, fieldHeight3];
                end
            end

            % Button Interactions
            app.ClearResButton_2.Enable = "on";
            app.ClearAllButton_2.Enable = 'on';
        end

        % Button pushed function: ClearResButton_2
        function ClearResButton_2Pushed(app, event)
            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end
            
            app.ClearResButton_2.Enable = "off";
        end

        % Button pushed function: ClearAllButton_2
        function ClearAllButton_2Pushed(app, event)
            if ~isempty(app.DisplayPanel1_2)
                delete(app.DisplayPanel1_2.Children)
            end

            if ~isempty(app.ResultPanel_2)
                delete(app.ResultPanel_2.Children)
            end

            app.TransposeButton.Enable = "off";
            app.DeterminantButton.Enable = "off";
            app.InverseButton.Enable = "off";
            app.ClearResButton_2.Enable = "off";
            app.ClearAllButton_2.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MatrixAppUIFigure = uifigure('Visible', 'off');
            app.MatrixAppUIFigure.Name = 'Matrix App';
            app.MatrixArithmeticPanel = uipanel(app.MatrixAppUIFigure);
            app.MatrixArithmeticPanel.Visible = 'off';
            app.MatrixArithmeticImage = uiimage(app.MatrixArithmeticPanel);
            app.MatrixArithmeticImage.ImageSource = fullfile(pathToMLAPP, 'public', 'img', 'MatrixArithmetic.png');
            app.DisplayPanel1 = uipanel(app.MatrixArithmeticPanel);
            app.DisplayPanel2 = uipanel(app.MatrixArithmeticPanel);
            app.NumberofRowsSpinnerLabel = uilabel(app.MatrixArithmeticPanel);
            app.NumberofRowsSpinnerLabel.Text = 'Number of Rows';
            app.numRowSpinner = uispinner(app.MatrixArithmeticPanel);
            app.numRowSpinner.ValueChangingFcn = createCallbackFcn(app, @numRowSpinnerValueChanging, true);
            app.numRowSpinner.Limits = [1 5];
            app.numRowSpinner.RoundFractionalValues = 'on';
            app.numRowSpinner.Value = 1;
            app.NumberofColumnsSpinnerLabel = uilabel(app.MatrixArithmeticPanel);
            app.NumberofColumnsSpinnerLabel.Text = 'Number of Columns';
            app.numColSpinner = uispinner(app.MatrixArithmeticPanel);
            app.numColSpinner.ValueChangingFcn = createCallbackFcn(app, @numColSpinnerValueChanging, true);
            app.numColSpinner.Limits = [1 5];
            app.numColSpinner.RoundFractionalValues = 'on';
            app.numColSpinner.Value = 1;
            app.NumberofRowsSpinnerLabel_2 = uilabel(app.MatrixArithmeticPanel);
            app.NumberofRowsSpinnerLabel_2.Text = 'Number of Rows';
            app.numRowSpinner2 = uispinner(app.MatrixArithmeticPanel);
            app.numRowSpinner2.ValueChangingFcn = createCallbackFcn(app, @numRowSpinner2ValueChanging, true);
            app.numRowSpinner2.Limits = [1 5];
            app.numRowSpinner2.RoundFractionalValues = 'on';
            app.numRowSpinner2.Value = 1;
            app.NumberofColumnsSpinnerLabel_2 = uilabel(app.MatrixArithmeticPanel);
            app.NumberofColumnsSpinnerLabel_2.Text = 'Number of Columns';
            app.numColSpinner2 = uispinner(app.MatrixArithmeticPanel);
            app.numColSpinner2.ValueChangingFcn = createCallbackFcn(app, @numColSpinner2ValueChanging, true);
            app.numColSpinner2.Limits = [1 5];
            app.numColSpinner2.RoundFractionalValues = 'on';
            app.numColSpinner2.Value = 1;
            app.Panel = uipanel(app.MatrixArithmeticPanel);
            app.DisplayButton = uibutton(app.MatrixArithmeticPanel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.CalculateButton = uibutton(app.MatrixArithmeticPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ADDSwitch = uiswitch(app.MatrixArithmeticPanel, 'toggle');
            app.ADDSwitch.ValueChangedFcn = createCallbackFcn(app, @ADDSwitchValueChanged, true);
            app.SUBSwitch = uiswitch(app.MatrixArithmeticPanel, 'toggle');
            app.SUBSwitch.ValueChangedFcn = createCallbackFcn(app, @SUBSwitchValueChanged, true);
            app.MULSwitch = uiswitch(app.MatrixArithmeticPanel, 'toggle');
            app.MULSwitch.ValueChangedFcn = createCallbackFcn(app, @MULSwitchValueChanged, true);
            app.ClearResButton = uibutton(app.MatrixArithmeticPanel, 'push');
            app.ClearResButton.ButtonPushedFcn = createCallbackFcn(app, @ClearResButtonPushed, true);
            app.ClearResButton.Text = 'Clear Result';
            app.ADDSwitchLabel = uilabel(app.MatrixArithmeticPanel);
            app.ADDSwitchLabel.Text = 'ADD';
            app.SUBLabel = uilabel(app.MatrixArithmeticPanel);
            app.SUBLabel.Text = 'SUB';
            app.MULSwitchLabel = uilabel(app.MatrixArithmeticPanel);
            app.MULSwitchLabel.Text = 'MUL';
            app.ADDLamp = uilamp(app.MatrixArithmeticPanel);
            app.SUBLamp = uilamp(app.MatrixArithmeticPanel);
            app.MULLamp = uilamp(app.MatrixArithmeticPanel);
            app.ClearAllButton = uibutton(app.MatrixArithmeticPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.TabGroup = uitabgroup(app.MatrixAppUIFigure);
            app.ArithmeticTab = uitab(app.TabGroup);
            app.ArithmeticTab.Title = 'Arithmetic';
            app.MatrixCalculatorPanel = uipanel(app.ArithmeticTab);
            app.MatrixCalculatorPanel.Title = 'Matrix Calculator';
            app.MatrixFunctionsTab = uitab(app.TabGroup);
            app.MatrixFunctionsTab.Title = 'Matrix Functions';
            app.MatrixFunctionsPanel = uipanel(app.MatrixFunctionsTab);
            app.MatrixFunctionsPanel.Title = 'Matrix Functions';
            app.DisplayPanel1_2 = uipanel(app.MatrixFunctionsPanel);
            app.DisplayButton_2 = uibutton(app.MatrixFunctionsPanel, 'push');
            app.DisplayButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisplayButton_2Pushed, true);
            app.DisplayButton_2.Text = 'Display';
            app.NumberofRowsSpinnerLabel_3 = uilabel(app.MatrixFunctionsPanel);
            app.NumberofRowsSpinnerLabel_3.Text = 'Number of Rows';
            app.numRowSpinner_2 = uispinner(app.MatrixFunctionsPanel);
            app.numRowSpinner_2.ValueChangingFcn = createCallbackFcn(app, @numRowSpinner_2ValueChanging, true);
            app.numRowSpinner_2.Limits = [1 5];
            app.numRowSpinner_2.Value = 1;
            app.NumberofColumnsSpinnerLabel_3 = uilabel(app.MatrixFunctionsPanel);
            app.NumberofColumnsSpinnerLabel_3.Text = 'Number of Columns';
            app.numColSpinner_2 = uispinner(app.MatrixFunctionsPanel);
            app.numColSpinner_2.ValueChangingFcn = createCallbackFcn(app, @numColSpinner_2ValueChanging, true);
            app.numColSpinner_2.Limits = [1 5];
            app.numColSpinner_2.Value = 1;
            app.Matrix1Label_2 = uilabel(app.MatrixFunctionsPanel);
            app.Matrix1Label_2.Text = 'Matrix 1';
            app.ResultPanel_2 = uipanel(app.MatrixFunctionsPanel);
            app.ClearAllButton_2 = uibutton(app.MatrixFunctionsPanel, 'push');
            app.ClearAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_2Pushed, true);
            app.ClearAllButton_2.Enable = 'off';
            app.ClearAllButton_2.Text = 'Clear All';
            app.ClearResButton_2 = uibutton(app.MatrixFunctionsPanel, 'push');
            app.ClearResButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearResButton_2Pushed, true);
            app.ClearResButton_2.Enable = 'off';
            app.ClearResButton_2.Text = 'Clear Result';
            app.TransposeButton = uibutton(app.MatrixFunctionsPanel, 'push');
            app.TransposeButton.ButtonPushedFcn = createCallbackFcn(app, @TransposeButtonPushed, true);
            app.TransposeButton.Enable = 'off';
            app.TransposeButton.Text = 'Transpose';
            app.DeterminantButton = uibutton(app.MatrixFunctionsPanel, 'push');
            app.DeterminantButton.ButtonPushedFcn = createCallbackFcn(app, @DeterminantButtonPushed, true);
            app.DeterminantButton.Enable = 'off';
            app.DeterminantButton.Text = 'Determinant';
            app.InverseButton = uibutton(app.MatrixFunctionsPanel, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Enable = 'off';
            app.InverseButton.Text = 'Inverse';
            app.OptionsPanel = uipanel(app.MatrixAppUIFigure);
            app.OptionsImage = uiimage(app.OptionsPanel);
            app.OptionsImage.ImageSource = fullfile(pathToMLAPP, 'public', 'img', 'Options.png');
            app.OptionsNextButton = uiimage(app.OptionsPanel);
            app.OptionsNextButton.ImageSource = fullfile(pathToMLAPP, 'public', 'img', 'transparent.png');
            app.OptionsHomeButton = uiimage(app.OptionsPanel);
            app.OptionsHomeButton.ImageClickedFcn = createCallbackFcn(app, @OptionsHomeButtonImageClicked, true);
            app.OptionsHomeButton.ImageSource = fullfile(pathToMLAPP, 'public', 'img', 'transparent.png');
            app.OptionsMatrixArithButton = uiimage(app.OptionsPanel);
            app.OptionsMatrixArithButton.ImageClickedFcn = createCallbackFcn(app, @OptionsMatrixArithButtonImageClicked, true);
            app.OptionsMatrixArithButton.ImageSource = fullfile(pathToMLAPP, 'public', 'img', 'transparent.png');
            app.HomePanel = uipanel(app.MatrixAppUIFigure);
            app.HomeImage = uiimage(app.HomePanel);
            app.HomeImage.ImageSource = fullfile(pathToMLAPP, 'public', 'img', 'Home.png');
            app.HomeNextButton = uiimage(app.HomePanel);
            app.HomeNextButton.ImageClickedFcn = createCallbackFcn(app, @HomeNextButtonImageClicked, true);
            app.HomeNextButton.ImageSource = fullfile(pathToMLAPP, 'public', 'img', 'transparent.png');
            app.HomeDeveloperButton = uiimage(app.HomePanel);
            app.HomeDeveloperButton.ImageClickedFcn = createCallbackFcn(app, @HomeDeveloperButtonImageClicked, true);
            app.HomeDeveloperButton.ImageSource = fullfile(pathToMLAPP, 'public', 'img', 'transparent.png');
            % Show the figure after all components are created
            app.MatrixAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatrixAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatrixAppUIFigure)
        end
    end
end