classdef tryy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab2                matlab.ui.container.Tab
        Button2             matlab.ui.control.Button
        nextButton          matlab.ui.control.Button
        detbuttonButton     matlab.ui.control.Button
        EditField           matlab.ui.control.NumericEditField
        TransposePanel      matlab.ui.container.Panel
        trn11               matlab.ui.control.NumericEditField
        trn12               matlab.ui.control.NumericEditField
        trn13               matlab.ui.control.NumericEditField
        trn14               matlab.ui.control.NumericEditField
        trn15               matlab.ui.control.NumericEditField
        trn21               matlab.ui.control.NumericEditField
        trn22               matlab.ui.control.NumericEditField
        trn23               matlab.ui.control.NumericEditField
        trn24               matlab.ui.control.NumericEditField
        trn25               matlab.ui.control.NumericEditField
        trn31               matlab.ui.control.NumericEditField
        trn32               matlab.ui.control.NumericEditField
        trn33               matlab.ui.control.NumericEditField
        trn34               matlab.ui.control.NumericEditField
        trn35               matlab.ui.control.NumericEditField
        trn41               matlab.ui.control.NumericEditField
        trn42               matlab.ui.control.NumericEditField
        trn43               matlab.ui.control.NumericEditField
        trn44               matlab.ui.control.NumericEditField
        trn45               matlab.ui.control.NumericEditField
        trn51               matlab.ui.control.NumericEditField
        trn52               matlab.ui.control.NumericEditField
        trn53               matlab.ui.control.NumericEditField
        trn54               matlab.ui.control.NumericEditField
        trn55               matlab.ui.control.NumericEditField
        InverseMatrixPanel  matlab.ui.container.Panel
        inv11               matlab.ui.control.NumericEditField
        inv12               matlab.ui.control.NumericEditField
        inv13               matlab.ui.control.NumericEditField
        inv14               matlab.ui.control.NumericEditField
        inv15               matlab.ui.control.NumericEditField
        inv21               matlab.ui.control.NumericEditField
        inv22               matlab.ui.control.NumericEditField
        inv23               matlab.ui.control.NumericEditField
        inv24               matlab.ui.control.NumericEditField
        inv25               matlab.ui.control.NumericEditField
        inv31               matlab.ui.control.NumericEditField
        inv32               matlab.ui.control.NumericEditField
        inv33               matlab.ui.control.NumericEditField
        inv34               matlab.ui.control.NumericEditField
        inv35               matlab.ui.control.NumericEditField
        inv41               matlab.ui.control.NumericEditField
        inv42               matlab.ui.control.NumericEditField
        inv43               matlab.ui.control.NumericEditField
        inv44               matlab.ui.control.NumericEditField
        inv45               matlab.ui.control.NumericEditField
        inv51               matlab.ui.control.NumericEditField
        inv52               matlab.ui.control.NumericEditField
        inv53               matlab.ui.control.NumericEditField
        inv54               matlab.ui.control.NumericEditField
        inv55               matlab.ui.control.NumericEditField
        AdjointPanel        matlab.ui.container.Panel
        adj11               matlab.ui.control.NumericEditField
        adj12               matlab.ui.control.NumericEditField
        adj13               matlab.ui.control.NumericEditField
        adj14               matlab.ui.control.NumericEditField
        adj15               matlab.ui.control.NumericEditField
        adj21               matlab.ui.control.NumericEditField
        adj22               matlab.ui.control.NumericEditField
        adj23               matlab.ui.control.NumericEditField
        adj24               matlab.ui.control.NumericEditField
        adj25               matlab.ui.control.NumericEditField
        adj31               matlab.ui.control.NumericEditField
        adj32               matlab.ui.control.NumericEditField
        adj33               matlab.ui.control.NumericEditField
        adj34               matlab.ui.control.NumericEditField
        adj35               matlab.ui.control.NumericEditField
        adj41               matlab.ui.control.NumericEditField
        adj42               matlab.ui.control.NumericEditField
        adj43               matlab.ui.control.NumericEditField
        adj44               matlab.ui.control.NumericEditField
        adj45               matlab.ui.control.NumericEditField
        adj51               matlab.ui.control.NumericEditField
        adj52               matlab.ui.control.NumericEditField
        adj53               matlab.ui.control.NumericEditField
        adj54               matlab.ui.control.NumericEditField
        adj55               matlab.ui.control.NumericEditField
        ButtonGroup         matlab.ui.container.ButtonGroup
        x5Btn               matlab.ui.control.RadioButton
        x4Btn               matlab.ui.control.RadioButton
        x3Btn               matlab.ui.control.RadioButton
        x2Btn               matlab.ui.control.RadioButton
        A11                 matlab.ui.control.NumericEditField
        A12                 matlab.ui.control.NumericEditField
        A13                 matlab.ui.control.NumericEditField
        A14                 matlab.ui.control.NumericEditField
        A15                 matlab.ui.control.NumericEditField
        A21                 matlab.ui.control.NumericEditField
        A22                 matlab.ui.control.NumericEditField
        A23                 matlab.ui.control.NumericEditField
        A24                 matlab.ui.control.NumericEditField
        A25                 matlab.ui.control.NumericEditField
        A31                 matlab.ui.control.NumericEditField
        A32                 matlab.ui.control.NumericEditField
        A33                 matlab.ui.control.NumericEditField
        A34                 matlab.ui.control.NumericEditField
        A35                 matlab.ui.control.NumericEditField
        A41                 matlab.ui.control.NumericEditField
        A42                 matlab.ui.control.NumericEditField
        A43                 matlab.ui.control.NumericEditField
        A44                 matlab.ui.control.NumericEditField
        A45                 matlab.ui.control.NumericEditField
        A51                 matlab.ui.control.NumericEditField
        A52                 matlab.ui.control.NumericEditField
        A53                 matlab.ui.control.NumericEditField
        A54                 matlab.ui.control.NumericEditField
        A55                 matlab.ui.control.NumericEditField
        Tab3                matlab.ui.container.Tab
        AddButton_2         matlab.ui.control.Button
        MATRIXBPanel        matlab.ui.container.Panel
        RowxColumnMatB      matlab.ui.control.Button
        ColumnSpinMatB      matlab.ui.control.Spinner
        ColumnLabel         matlab.ui.control.Label
        RowSpinMatB         matlab.ui.control.Spinner
        RowSpinnerLabel     matlab.ui.control.Label
        B11                 matlab.ui.control.NumericEditField
        B12                 matlab.ui.control.NumericEditField
        B13                 matlab.ui.control.NumericEditField
        B14                 matlab.ui.control.NumericEditField
        B15                 matlab.ui.control.NumericEditField
        B21                 matlab.ui.control.NumericEditField
        B22                 matlab.ui.control.NumericEditField
        B23                 matlab.ui.control.NumericEditField
        B24                 matlab.ui.control.NumericEditField
        B25                 matlab.ui.control.NumericEditField
        B31                 matlab.ui.control.NumericEditField
        B32                 matlab.ui.control.NumericEditField
        B33                 matlab.ui.control.NumericEditField
        B34                 matlab.ui.control.NumericEditField
        B35                 matlab.ui.control.NumericEditField
        B41                 matlab.ui.control.NumericEditField
        B42                 matlab.ui.control.NumericEditField
        B43                 matlab.ui.control.NumericEditField
        B44                 matlab.ui.control.NumericEditField
        B45                 matlab.ui.control.NumericEditField
        B51                 matlab.ui.control.NumericEditField
        B52                 matlab.ui.control.NumericEditField
        B53                 matlab.ui.control.NumericEditField
        B54                 matlab.ui.control.NumericEditField
        B55                 matlab.ui.control.NumericEditField
        MATRIXCPanel        matlab.ui.container.Panel
        RowxColumnMatC      matlab.ui.control.Button
        ColumnSpinMatC      matlab.ui.control.Spinner
        ColumnLabel_2       matlab.ui.control.Label
        RowSpinMatC         matlab.ui.control.Spinner
        RowSpinnerLabel_2   matlab.ui.control.Label
        C11                 matlab.ui.control.NumericEditField
        C12                 matlab.ui.control.NumericEditField
        C13                 matlab.ui.control.NumericEditField
        C14                 matlab.ui.control.NumericEditField
        C15                 matlab.ui.control.NumericEditField
        C21                 matlab.ui.control.NumericEditField
        C22                 matlab.ui.control.NumericEditField
        C23                 matlab.ui.control.NumericEditField
        C24                 matlab.ui.control.NumericEditField
        C25                 matlab.ui.control.NumericEditField
        C31                 matlab.ui.control.NumericEditField
        C32                 matlab.ui.control.NumericEditField
        C33                 matlab.ui.control.NumericEditField
        C34                 matlab.ui.control.NumericEditField
        C35                 matlab.ui.control.NumericEditField
        C41                 matlab.ui.control.NumericEditField
        C42                 matlab.ui.control.NumericEditField
        C43                 matlab.ui.control.NumericEditField
        C44                 matlab.ui.control.NumericEditField
        C45                 matlab.ui.control.NumericEditField
        C51                 matlab.ui.control.NumericEditField
        C52                 matlab.ui.control.NumericEditField
        C53                 matlab.ui.control.NumericEditField
        C54                 matlab.ui.control.NumericEditField
        C55                 matlab.ui.control.NumericEditField
        MATRIXDPanel        matlab.ui.container.Panel
        D11                 matlab.ui.control.NumericEditField
        D12                 matlab.ui.control.NumericEditField
        D13                 matlab.ui.control.NumericEditField
        D14                 matlab.ui.control.NumericEditField
        D15                 matlab.ui.control.NumericEditField
        D21                 matlab.ui.control.NumericEditField
        D22                 matlab.ui.control.NumericEditField
        D23                 matlab.ui.control.NumericEditField
        D24                 matlab.ui.control.NumericEditField
        D25                 matlab.ui.control.NumericEditField
        D31                 matlab.ui.control.NumericEditField
        D32                 matlab.ui.control.NumericEditField
        D33                 matlab.ui.control.NumericEditField
        D34                 matlab.ui.control.NumericEditField
        D35                 matlab.ui.control.NumericEditField
        D41                 matlab.ui.control.NumericEditField
        D42                 matlab.ui.control.NumericEditField
        D43                 matlab.ui.control.NumericEditField
        D44                 matlab.ui.control.NumericEditField
        D45                 matlab.ui.control.NumericEditField
        D51                 matlab.ui.control.NumericEditField
        D52                 matlab.ui.control.NumericEditField
        D53                 matlab.ui.control.NumericEditField
        D54                 matlab.ui.control.NumericEditField
        D55                 matlab.ui.control.NumericEditField
        AddButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: detbuttonButton
        function detbuttonButtonPushed(app, event)
            %variables
            A11 = app.A11.Value;
            A12 = app.A12.Value;
            A13 = app.A13.Value;
            A14 = app.A14.Value;
            A15 = app.A15.Value;
            A21 = app.A21.Value;
            A22 = app.A22.Value;
            A23 = app.A23.Value;
            A24 = app.A24.Value;
            A25 = app.A25.Value;
            A31 = app.A31.Value;
            A32 = app.A32.Value;
            A33 = app.A33.Value;
            A34 = app.A34.Value;
            A35 = app.A35.Value;
            A41 = app.A41.Value;
            A42 = app.A42.Value;
            A43 = app.A43.Value;
            A44 = app.A44.Value;
            A45 = app.A45.Value;
            A51 = app.A51.Value;
            A52 = app.A52.Value;
            A53 = app.A53.Value;
            A54 = app.A54.Value;
            A55 = app.A55.Value;
            
            selectedButton = app.ButtonGroup.SelectedObject;
            
            %determinants
            if selectedButton == app.x2Btn
                DetA = (A11*A22)-(A12*A21);
                app.EditField.Value = DetA;
            end
            if selectedButton == app.x3Btn
                %sarrus method
                DetA = (A11*A22*A33)+(A12*A23*A31)+(A13*A21*A32)-(A13*A22*A31)-(A11*A23*A32)-(A12*A21*A33);
                app.EditField.Value = DetA;
            end
            if selectedButton == app.x4Btn
                %expansion method
                w = A11*((A22*A33*A44)+(A23*A34*A42)+(A24*A32*A43)-(A42*A33*A24)-(A43*A34*A22)-(A44*A32*A23));
                x = A12*((A21*A33*A44)+(A23*A34*A41)+(A24*A31*A43)-(A41*A33*A24)-(A43*A34*A21)-(A44*A31*A23));
                y = A13*((A21*A32*A44)+(A22*A34*A41)+(A24*A31*A42)-(A41*A32*A24)-(A42*A34*A21)-(A44*A31*A22));
                z = A14*((A21*A32*A43)+(A22*A33*A41)+(A23*A31*A42)-(A41*A32*A23)-(A42*A33*A21)-(A43*A31*A22));
                DetA = w-x+y-z;
                app.EditField.Value = DetA;
            end
            if selectedButton == app.x5Btn
                %expansion method
                v1 = A11*A22*(A33*A44*A55 + A34*A45*A53 + A35*A43*A54 - A35*A44*A53 - A34*A43*A55 - A33*A45*A54);
                v2 = -A11*A32*(A23*A44*A55 + A24*A45*A53 + A25*A43*A54 - A25*A44*A53 - A24*A43*A55 - A23*A45*A54);
                v3 = A11*A42*(A23*A34*A55 + A24*A35*A53 + A25*A33*A54 - A25*A34*A53 - A24*A33*A55 - A23*A35*A54);
                v4 = -A11*A52*(A23*A34*A45 + A24*A35*A43 + A25*A33*A44 - A25*A34*A43 - A24*A33*A45 - A23*A35*A44);
                v = v1 + v2 + v3 + v4;
                w1 = -A21*A12*(A33*A44*A55 + A34*A45*A53 + A35*A43*A54 - A35*A44*A53 - A34*A43*A55 - A33*A45*A54);
                w2 = A21*A32*(A13*A44*A55 + A14*A45*A53 + A15*A43*A54 - A15*A44*A53 - A14*A43*A55 - A13*A45*A54);
                w3 = -A21*A42*(A13*A34*A55 + A14*A35*A53 + A15*A33*A54 - A15*A34*A53 - A14*A33*A55 - A13*A35*A54);
                w4 = A21*A52*(A13*A34*A45 + A14*A35*A43 + A15*A33*A44 - A15*A34*A43 - A14*A33*A45 - A13*A35*A44);
                w = w1 + w2 + w3 + w4;
                x1 = A31*A12*(A23*A44*A55 + A24*A45*A53 + A25*A43*A54 - A25*A44*A53 - A24*A43*A55 - A23*A45*A54);
                x2 = -A31*A22*(A13*A44*A55 + A14*A45*A53 + A15*A43*A54 - A15*A44*A53 - A14*A43*A55 - A13*A45*A54);
                x3 = A31*A42*(A13*A24*A55 + A14*A25*A53 + A15*A23*A54 - A15*A24*A53 - A14*A23*A55 - A13*A25*A54);
                x4 = -A31*A52*(A13*A24*A45 + A14*A25*A43 + A15*A23*A44 - A15*A24*A43 - A14*A23*A45 - A13*A25*A44);
                x = x1 + x2 + x3 + x4;
                y1 = -A41*A12*(A23*A34*A55 + A24*A35*A53 + A25*A33*A54 - A25*A34*A53 - A24*A33*A55 - A23*A35*A54);
                y2 = A41*A22*(A13*A34*A55 + A14*A35*A53 + A15*A33*A54 - A15*A34*A53 - A14*A33*A55 - A13*A35*A54);
                y3 = -A41*A32*(A13*A24*A55 + A14*A25*A53 + A15*A23*A54 - A15*A24*A53 - A14*A23*A55 - A13*A25*A54);
                y4 = A41*A52*(A13*A24*A35 + A14*A25*A33 + A15*A23*A34 - A15*A24*A33 - A14*A23*A35 - A13*A25*A34);
                y = y1 + y2 + y3 + y4;
                z1 = A51*A12*(A23*A34*A45 + A24*A35*A43 + A25*A33*A44 - A25*A34*A43 - A24*A33*A45 - A23*A35*A44);
                z2 = -A51*A22*(A13*A34*A45 + A14*A35*A43 + A15*A33*A44 - A15*A34*A43 - A14*A33*A45 - A13*A35*A44);
                z3 = A51*A32*(A13*A24*A45 + A14*A25*A43 + A15*A23*A44 - A15*A24*A43 - A14*A23*A45 - A13*A25*A44);
                z4 = -A51*A42*(A13*A24*A35 + A14*A25*A33 + A15*A23*A34 - A15*A24*A33 - A14*A23*A35 - A13*A25*A34);
                z = z1 + z2 + z3 + z4;
                DetA = v+w+x+y+z;
                app.EditField.Value = DetA;
            end

            %adjoint
            if selectedButton == app.x2Btn
                DetA = [A11, A12; A21, A22];
                adj = adjoint(DetA);
                app.adj11.Value = adj(1,1);
                app.adj12.Value = adj(1,2);
                app.adj21.Value = adj(2,1);
                app.adj22.Value = adj(2,2);
            end
            if selectedButton == app.x3Btn
                DetA = [A11, A12, A13; A21, A22, A23; A31, A32, A33];
                adj = adjoint(DetA);
                app.adj11.Value = adj(1,1);
                app.adj12.Value = adj(1,2);
                app.adj13.Value = adj(1,3);
                app.adj21.Value = adj(2,1);
                app.adj22.Value = adj(2,2);
                app.adj23.Value = adj(2,3);
                app.adj31.Value = adj(3,1);
                app.adj32.Value = adj(3,2);
                app.adj33.Value = adj(3,3);
            end
            if selectedButton == app.x4Btn
                DetA = [A11, A12, A13, A14; A21, A22, A23, A24; A31, A32, A33, A34; A41, A42, A43, A44];
                adj = adjoint(DetA);
                app.adj11.Value = adj(1,1);
                app.adj12.Value = adj(1,2);
                app.adj13.Value = adj(1,3);
                app.adj14.Value = adj(1,4);
                app.adj21.Value = adj(2,1);
                app.adj22.Value = adj(2,2);
                app.adj23.Value = adj(2,3);
                app.adj24.Value = adj(2,4);
                app.adj31.Value = adj(3,1);
                app.adj32.Value = adj(3,2);
                app.adj33.Value = adj(3,3);
                app.adj34.Value = adj(3,4);
                app.adj41.Value = adj(4,1);
                app.adj42.Value = adj(4,2);
                app.adj43.Value = adj(4,3);
                app.adj44.Value = adj(4,4);
            end
            if selectedButton == app.x5Btn
                DetA = [A11, A12, A13, A14, A15; A21, A22, A23, A24, A25; A31, A32, A33, A34, A35; A41, A42, A43, A44, A45; A51, A52, A53, A54, A55];
                adj = adjoint(DetA);
                app.adj11.Value = adj(1,1);
                app.adj12.Value = adj(1,2);
                app.adj13.Value = adj(1,3);
                app.adj14.Value = adj(1,4);
                app.adj15.Value = adj(1,5);
                app.adj21.Value = adj(2,1);
                app.adj22.Value = adj(2,2);
                app.adj23.Value = adj(2,3);
                app.adj24.Value = adj(2,4);
                app.adj25.Value = adj(2,5);
                app.adj31.Value = adj(3,1);
                app.adj32.Value = adj(3,2);
                app.adj33.Value = adj(3,3);
                app.adj34.Value = adj(3,4);
                app.adj35.Value = adj(3,5);
                app.adj41.Value = adj(4,1);
                app.adj42.Value = adj(4,2);
                app.adj43.Value = adj(4,3);
                app.adj44.Value = adj(4,4);
                app.adj45.Value = adj(4,5);
                app.adj51.Value = adj(5,1);
                app.adj52.Value = adj(5,2);
                app.adj53.Value = adj(5,3);
                app.adj54.Value = adj(5,4);
                app.adj55.Value = adj(5,5);
            end
           
            %transpose
            if selectedButton == app.x2Btn
                DetA = [A11, A12; A21, A22];
                trn = DetA';
                app.trn11.Value = trn(1,1);
                app.trn12.Value = trn(1,2);
                app.trn21.Value = trn(2,1);
                app.trn22.Value = trn(2,2);
            end 
            if selectedButton == app.x3Btn
                DetA = [A11, A12, A13; A21, A22, A23; A31, A32, A33];
                trn = DetA';
                app.trn11.Value = trn(1,1);
                app.trn12.Value = trn(1,2);
                app.trn13.Value = trn(1,3);
                app.trn21.Value = trn(2,1);
                app.trn22.Value = trn(2,2);
                app.trn23.Value = trn(2,3);
                app.trn31.Value = trn(3,1);
                app.trn32.Value = trn(3,2);
                app.trn33.Value = trn(3,3);
            end
            if selectedButton == app.x4Btn
                DetA = [A11, A12, A13, A14; A21, A22, A23, A24; A31, A32, A33, A34; A41, A42, A43, A44];
                trn = DetA';
                app.trn11.Value = trn(1,1);
                app.trn12.Value = trn(1,2);
                app.trn13.Value = trn(1,3);
                app.trn14.Value = trn(1,4);
                app.trn21.Value = trn(2,1);
                app.trn22.Value = trn(2,2);
                app.trn23.Value = trn(2,3);
                app.trn24.Value = trn(2,4);
                app.trn31.Value = trn(3,1);
                app.trn32.Value = trn(3,2);
                app.trn33.Value = trn(3,3);
                app.trn34.Value = trn(3,4);
                app.trn41.Value = trn(4,1);
                app.trn42.Value = trn(4,2);
                app.trn43.Value = trn(4,3);
                app.trn44.Value = trn(4,4);
            end
            if selectedButton == app.x5Btn
                DetA = [A11, A12, A13, A14, A15; A21, A22, A23, A24, A25; A31, A32, A33, A34, A35; A41, A42, A43, A44, A45; A51, A52, A53, A54, A55];
                trn = DetA';
                app.trn11.Value = trn(1,1);
                app.trn12.Value = trn(1,2);
                app.trn13.Value = trn(1,3);
                app.trn14.Value = trn(1,4);
                app.trn15.Value = trn(1,5);
                app.trn21.Value = trn(2,1);
                app.trn22.Value = trn(2,2);
                app.trn23.Value = trn(2,3);
                app.trn24.Value = trn(2,4);
                app.trn25.Value = trn(2,5);
                app.trn31.Value = trn(3,1);
                app.trn32.Value = trn(3,2);
                app.trn33.Value = trn(3,3);
                app.trn34.Value = trn(3,4);
                app.trn35.Value = trn(3,5);
                app.trn41.Value = trn(4,1);
                app.trn42.Value = trn(4,2);
                app.trn43.Value = trn(4,3);
                app.trn44.Value = trn(4,4);
                app.trn45.Value = trn(4,5);
                app.trn51.Value = trn(5,1);
                app.trn52.Value = trn(5,2);
                app.trn53.Value = trn(5,3);
                app.trn54.Value = trn(5,4);
                app.trn55.Value = trn(5,5);
            end

            %inverse
            if  app.EditField.Value == 0 
                msgbox("Warning: The matrix is singular, therefore the inverse of the matrix does not exist.")
                if selectedButton == app.x2Btn 
                    DetA = [A11, A12; A21, A22];
                    app.inv11.Value = 0;
                    app.inv12.Value = 0;
                    app.inv21.Value = 0;
                    app.inv22.Value = 0;
                end
                if selectedButton == app.x3Btn 
                    DetA = [A11, A12, A13; A21, A22, A23; A31, A32, A33];
                    app.inv11.Value = 0;
                    app.inv12.Value = 0;
                    app.inv13.Value = 0;
                    app.inv21.Value = 0;
                    app.inv22.Value = 0;
                    app.inv23.Value = 0;
                    app.inv31.Value = 0;
                    app.inv32.Value = 0;
                    app.inv33.Value = 0;
                end
                if selectedButton == app.x4Btn
                    DetA = [A11, A12, A13, A14; A21, A22, A23, A24; A31, A32, A33, A34; A41, A42, A43, A44];

                    app.inv11.Value = 0;
                    app.inv12.Value = 0;
                    app.inv13.Value = 0;
                    app.inv14.Value = 0;
                    app.inv21.Value = 0;
                    app.inv22.Value = 0;
                    app.inv23.Value = 0;
                    app.inv24.Value = 0;
                    app.inv31.Value = 0;
                    app.inv32.Value = 0;
                    app.inv33.Value = 0;
                    app.inv34.Value = 0;
                    app.inv41.Value = 0;
                    app.inv42.Value = 0;
                    app.inv43.Value = 0;
                    app.inv44.Value = 0;
                end
                if selectedButton == app.x5Btn
                    DetA = [A11, A12, A13, A14, A15; A21, A22, A23, A24, A25; A31, A32, A33, A34, A35; A41, A42, A43, A44, A45; A51, A52, A53, A54, A55];
                    app.inv11.Value = 0;
                    app.inv12.Value = 0;
                    app.inv13.Value = 0;
                    app.inv14.Value = 0;
                    app.inv15.Value = 0;
                    app.inv21.Value = 0;
                    app.inv22.Value = 0;
                    app.inv23.Value = 0;
                    app.inv24.Value = 0;
                    app.inv25.Value = 0;
                    app.inv31.Value = 0;
                    app.inv32.Value = 0;
                    app.inv33.Value = 0;
                    app.inv34.Value = 0;
                    app.inv35.Value = 0;
                    app.inv41.Value = 0;
                    app.inv42.Value = 0;
                    app.inv43.Value = 0;
                    app.inv44.Value = 0;
                    app.inv45.Value = 0;
                    app.inv51.Value = 0;
                    app.inv52.Value = 0;
                    app.inv53.Value = 0;
                    app.inv54.Value = 0;
                    app.inv55.Value = 0;
                end
            else
                if selectedButton == app.x2Btn 
                    DetA = [A11, A12; A21, A22];
                    inverse = adjoint(DetA)/app.EditField.Value;
                    app.inv11.Value = inverse(1,1);
                    app.inv12.Value = inverse(1,2);
                    app.inv21.Value = inverse(2,1);
                    app.inv22.Value = inverse(2,2);
                end
                if selectedButton == app.x3Btn 
                    DetA = [A11, A12, A13; A21, A22, A23; A31, A32, A33];
                    inverse = adjoint(DetA)/app.EditField.Value;
                    app.inv11.Value = inverse(1,1);
                    app.inv12.Value = inverse(1,2);
                    app.inv13.Value = inverse(1,3);
                    app.inv21.Value = inverse(2,1);
                    app.inv22.Value = inverse(2,2);
                    app.inv23.Value = inverse(2,3);
                    app.inv31.Value = inverse(3,1);
                    app.inv32.Value = inverse(3,2);
                    app.inv33.Value = inverse(3,3);
                end
                if selectedButton == app.x4Btn
                    DetA = [A11, A12, A13, A14; A21, A22, A23, A24; A31, A32, A33, A34; A41, A42, A43, A44];
                    inverse = adjoint(DetA)/app.EditField.Value;
                    app.inv11.Value = inverse(1,1);
                    app.inv12.Value = inverse(1,2);
                    app.inv13.Value = inverse(1,3);
                    app.inv14.Value = inverse(1,4);
                    app.inv21.Value = inverse(2,1);
                    app.inv22.Value = inverse(2,2);
                    app.inv23.Value = inverse(2,3);
                    app.inv24.Value = inverse(2,4);
                    app.inv31.Value = inverse(3,1);
                    app.inv32.Value = inverse(3,2);
                    app.inv33.Value = inverse(3,3);
                    app.inv34.Value = inverse(3,4);
                    app.inv41.Value = inverse(4,1);
                    app.inv42.Value = inverse(4,2);
                    app.inv43.Value = inverse(4,3);
                    app.inv44.Value = inverse(4,4);
                end
                if selectedButton == app.x5Btn
                    DetA = [A11, A12, A13, A14, A15; A21, A22, A23, A24, A25; A31, A32, A33, A34, A35; A41, A42, A43, A44, A45; A51, A52, A53, A54, A55];
                    inverse = adjoint(DetA)/app.EditField.Value;
                    app.inv11.Value = inverse(1,1);
                    app.inv12.Value = inverse(1,2);
                    app.inv13.Value = inverse(1,3);
                    app.inv14.Value = inverse(1,4);
                    app.inv15.Value = inverse(1,5);
                    app.inv21.Value = inverse(2,1);
                    app.inv22.Value = inverse(2,2);
                    app.inv23.Value = inverse(2,3);
                    app.inv24.Value = inverse(2,4);
                    app.inv25.Value = inverse(2,5);
                    app.inv31.Value = inverse(3,1);
                    app.inv32.Value = inverse(3,2);
                    app.inv33.Value = inverse(3,3);
                    app.inv34.Value = inverse(3,4);
                    app.inv35.Value = inverse(3,5);
                    app.inv41.Value = inverse(4,1);
                    app.inv42.Value = inverse(4,2);
                    app.inv43.Value = inverse(4,3);
                    app.inv44.Value = inverse(4,4);
                    app.inv45.Value = inverse(4,5);
                    app.inv51.Value = inverse(5,1);
                    app.inv52.Value = inverse(5,2);
                    app.inv53.Value = inverse(5,3); 
                    app.inv54.Value = inverse(5,4);
                    app.inv55.Value = inverse(5,5);
                end
           end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            %2x2 matrices
            if selectedButton == app.x2Btn
                    app.A11.Visible = "on";
                    app.A12.Visible = "on";
                app.A13.Visible = "off";
                app.A14.Visible = "off";
                app.A15.Visible = "off";
                    app.A21.Visible = "on";
                    app.A22.Visible = "on";
                app.A23.Visible = "off";
                app.A24.Visible = "off";
                app.A25.Visible = "off";
                app.A31.Visible = "off";
                app.A32.Visible = "off";
                app.A33.Visible = "off";
                app.A34.Visible = "off";
                app.A35.Visible = "off";
                app.A41.Visible = "off";
                app.A42.Visible = "off";
                app.A43.Visible = "off";
                app.A44.Visible = "off";
                app.A45.Visible = "off";
                app.A51.Visible = "off";
                app.A52.Visible = "off";
                app.A53.Visible = "off";
                app.A54.Visible = "off";
                app.A55.Visible = "off";
                
                %adjoint
                    app.adj11.Visible = "on";
                    app.adj12.Visible = "on";
                app.adj13.Visible = "off";
                app.adj14.Visible = "off";
                app.adj15.Visible = "off";
                    app.adj21.Visible = "on";
                    app.adj22.Visible = "on";
                app.adj23.Visible = "off";
                app.adj24.Visible = "off";
                app.adj25.Visible = "off";
                app.adj31.Visible = "off";
                app.adj32.Visible = "off";
                app.adj33.Visible = "off";
                app.adj34.Visible = "off";
                app.adj35.Visible = "off";
                app.adj41.Visible = "off";
                app.adj42.Visible = "off";
                app.adj43.Visible = "off";
                app.adj44.Visible = "off";
                app.adj45.Visible = "off";
                app.adj51.Visible = "off";
                app.adj52.Visible = "off";
                app.adj53.Visible = "off";
                app.adj54.Visible = "off";
                app.adj55.Visible = "off";

                %inverse
                    app.inv11.Visible = "on";
                    app.inv12.Visible = "on";
                app.inv13.Visible = "off";
                app.inv14.Visible = "off";
                app.inv15.Visible = "off";
                    app.inv21.Visible = "on";
                    app.inv22.Visible = "on";
                app.inv23.Visible = "off";
                app.inv24.Visible = "off";
                app.inv25.Visible = "off";
                app.inv31.Visible = "off";
                app.inv32.Visible = "off";
                app.inv33.Visible = "off";
                app.inv34.Visible = "off";
                app.inv35.Visible = "off";
                app.inv41.Visible = "off";
                app.inv42.Visible = "off";
                app.inv43.Visible = "off";
                app.inv44.Visible = "off";
                app.inv45.Visible = "off";
                app.inv51.Visible = "off";
                app.inv52.Visible = "off";
                app.inv53.Visible = "off";
                app.inv54.Visible = "off";
                app.inv55.Visible = "off";

                %transpose
                    app.trn11.Visible = "on";
                    app.trn12.Visible = "on";
                app.trn13.Visible = "off";
                app.trn14.Visible = "off";
                app.trn15.Visible = "off";
                    app.trn21.Visible = "on";
                    app.trn22.Visible = "on";
                app.trn23.Visible = "off";
                app.trn24.Visible = "off";
                app.trn25.Visible = "off";
                app.trn31.Visible = "off";
                app.trn32.Visible = "off";
                app.trn33.Visible = "off";
                app.trn34.Visible = "off";
                app.trn35.Visible = "off";
                app.trn41.Visible = "off";
                app.trn42.Visible = "off";
                app.trn43.Visible = "off";
                app.trn44.Visible = "off";
                app.trn45.Visible = "off";
                app.trn51.Visible = "off";
                app.trn52.Visible = "off";
                app.trn53.Visible = "off";
                app.trn54.Visible = "off";
                app.trn55.Visible = "off";
            end    
                
            %3x3 matrices
            if selectedButton == app.x3Btn
                    app.A11.Visible = "on";
                    app.A12.Visible = "on";
                    app.A13.Visible = "on";
                app.A14.Visible = "off";
                app.A15.Visible = "off";
                    app.A21.Visible = "on";
                    app.A22.Visible = "on";
                    app.A23.Visible = "on";
                app.A24.Visible = "off";
                app.A25.Visible = "off";
                    app.A31.Visible = "on";
                    app.A32.Visible = "on";
                    app.A33.Visible = "on";
                app.A34.Visible = "off";
                app.A35.Visible = "off";
                app.A41.Visible = "off";
                app.A42.Visible = "off";
                app.A43.Visible = "off";
                app.A44.Visible = "off"; 
                app.A45.Visible = "off";
                app.A51.Visible = "off";
                app.A52.Visible = "off";
                app.A53.Visible = "off";
                app.A54.Visible = "off";
                app.A55.Visible = "off";

                %adjoint
                    app.adj11.Visible = "on";
                    app.adj12.Visible = "on";
                    app.adj13.Visible = "on";
                app.adj14.Visible = "off";
                app.adj15.Visible = "off";
                    app.adj21.Visible = "on";
                    app.adj22.Visible = "on";
                    app.adj23.Visible = "on";
                app.adj24.Visible = "off";
                app.adj25.Visible = "off";
                    app.adj31.Visible = "on";
                    app.adj32.Visible = "on";
                    app.adj33.Visible = "on";
                app.adj34.Visible = "off";
                app.adj35.Visible = "off";
                app.adj41.Visible = "off";
                app.adj42.Visible = "off";
                app.adj43.Visible = "off";
                app.adj44.Visible = "off";
                app.adj45.Visible = "off";
                app.adj51.Visible = "off";
                app.adj52.Visible = "off";
                app.adj53.Visible = "off";
                app.adj54.Visible = "off";
                app.adj55.Visible = "off";

                %inverse
                    app.inv11.Visible = "on";
                    app.inv12.Visible = "on";
                    app.inv13.Visible = "on";
                app.inv14.Visible = "off";
                app.inv15.Visible = "off";
                    app.inv21.Visible = "on";
                    app.inv22.Visible = "on";
                    app.inv23.Visible = "on";
                app.inv24.Visible = "off";
                app.inv25.Visible = "off";
                    app.inv31.Visible = "on";
                    app.inv32.Visible = "on";
                    app.inv33.Visible = "on";
                app.inv34.Visible = "off";
                app.inv35.Visible = "off";
                app.inv41.Visible = "off";
                app.inv42.Visible = "off";
                app.inv43.Visible = "off";
                app.inv44.Visible = "off";
                app.inv45.Visible = "off";
                app.inv51.Visible = "off";
                app.inv52.Visible = "off";
                app.inv53.Visible = "off";
                app.inv54.Visible = "off";
                app.inv55.Visible = "off";

                %transpose
                    app.trn11.Visible = "on";
                    app.trn12.Visible = "on";
                    app.trn13.Visible = "on";
                app.trn14.Visible = "off";
                app.trn15.Visible = "off";
                    app.trn21.Visible = "on";
                    app.trn22.Visible = "on";
                    app.trn23.Visible = "on";
                app.trn24.Visible = "off";
                app.trn25.Visible = "off";
                    app.trn31.Visible = "on";
                    app.trn32.Visible = "on";
                    app.trn33.Visible = "on";
                app.trn34.Visible = "off";
                app.trn35.Visible = "off";
                app.trn41.Visible = "off";
                app.trn42.Visible = "off";
                app.trn43.Visible = "off";
                app.trn44.Visible = "off";
                app.trn45.Visible = "off";
                app.trn51.Visible = "off";
                app.trn52.Visible = "off";
                app.trn53.Visible = "off";
                app.trn54.Visible = "off";
                app.trn55.Visible = "off";
            end 
            
            %4x4 matrices
            if selectedButton == app.x4Btn
                        app.A11.Visible = "on";
                    app.A12.Visible = "on";
                    app.A13.Visible = "on";
                    app.A14.Visible = "on";
                app.A15.Visible = "off";
                    app.A21.Visible = "on";
                    app.A22.Visible = "on";
                    app.A23.Visible = "on";
                    app.A24.Visible = "on";
                app.A25.Visible = "off";
                    app.A31.Visible = "on";
                    app.A32.Visible = "on";
                    app.A33.Visible = "on";
                    app.A34.Visible = "on";
                app.A35.Visible = "off";
                    app.A41.Visible = "on";
                    app.A42.Visible = "on";
                    app.A43.Visible = "on";
                    app.A44.Visible = "on"; 
                app.A45.Visible = "off";
                app.A51.Visible = "off";
                app.A52.Visible = "off";
                app.A53.Visible = "off";
                app.A54.Visible = "off";
                app.A55.Visible = "off";

                %adjoint
                    app.adj11.Visible = "on";
                    app.adj12.Visible = "on";
                    app.adj13.Visible = "on";
                    app.adj14.Visible = "on";
                app.adj15.Visible = "off";
                    app.adj21.Visible = "on";
                    app.adj22.Visible = "on";
                    app.adj23.Visible = "on";
                    app.adj24.Visible = "on";
                app.adj25.Visible = "off";
                    app.adj31.Visible = "on";
                    app.adj32.Visible = "on";
                    app.adj33.Visible = "on";
                    app.adj34.Visible = "on";
                app.adj35.Visible = "off";
                    app.adj41.Visible = "on";
                    app.adj42.Visible = "on";
                    app.adj43.Visible = "on";
                    app.adj44.Visible = "on";
                app.adj45.Visible = "off";
                app.adj51.Visible = "off";
                app.adj52.Visible = "off";
                app.adj53.Visible = "off";
                app.adj54.Visible = "off";
                app.adj55.Visible = "off";

                %inverse
                    app.inv11.Visible = "on";
                    app.inv12.Visible = "on";
                    app.inv13.Visible = "on";
                    app.inv14.Visible = "on";
                app.inv15.Visible = "off";
                    app.inv21.Visible = "on";
                    app.inv22.Visible = "on";
                    app.inv23.Visible = "on";
                    app.inv24.Visible = "on";
                app.inv25.Visible = "off";
                    app.inv31.Visible = "on";
                    app.inv32.Visible = "on";
                    app.inv33.Visible = "on";
                    app.inv34.Visible = "on";
                app.inv35.Visible = "off";
                    app.inv41.Visible = "on";
                    app.inv42.Visible = "on";
                    app.inv43.Visible = "on";
                    app.inv44.Visible = "on";
                app.inv45.Visible = "off";
                app.inv51.Visible = "off";
                app.inv52.Visible = "off";
                app.inv53.Visible = "off";
                app.inv54.Visible = "off";
                app.inv55.Visible = "off";

                %transpose
                    app.trn11.Visible = "on";
                    app.trn12.Visible = "on";
                    app.trn13.Visible = "on";
                    app.trn14.Visible = "on";
                app.trn15.Visible = "off";
                    app.trn21.Visible = "on";
                    app.trn22.Visible = "on";
                    app.trn23.Visible = "on";
                    app.trn24.Visible = "on";
                app.trn25.Visible = "off";
                    app.trn31.Visible = "on";
                    app.trn32.Visible = "on";
                    app.trn33.Visible = "on";
                    app.trn34.Visible = "on";
                app.trn35.Visible = "off";
                    app.trn41.Visible = "on";
                    app.trn42.Visible = "on";
                    app.trn43.Visible = "on";
                    app.trn44.Visible = "on";
                app.trn45.Visible = "off";
                app.trn51.Visible = "off";
                app.trn52.Visible = "off";
                app.trn53.Visible = "off";
                app.trn54.Visible = "off";
                app.trn55.Visible = "off";
            end

            %5x5 matrices
            if selectedButton == app.x5Btn
                app.A11.Visible = "on";
                app.A12.Visible = "on";
                app.A13.Visible = "on";
                app.A14.Visible = "on";
                app.A15.Visible = "on";
                app.A21.Visible = "on";
                app.A22.Visible = "on";
                app.A23.Visible = "on";
                app.A24.Visible = "on";
                app.A25.Visible = "on";
                app.A31.Visible = "on";
                app.A32.Visible = "on";
                app.A33.Visible = "on";
                app.A34.Visible = "on";
                app.A35.Visible = "on";
                app.A41.Visible = "on";
                app.A42.Visible = "on";
                app.A43.Visible = "on";
                app.A44.Visible = "on";
                app.A45.Visible = "on";
                app.A51.Visible = "on";
                app.A52.Visible = "on";
                app.A53.Visible = "on";
                app.A54.Visible = "on";
                app.A55.Visible = "on"; 

                %adjoint
                app.adj11.Visible = "on";
                app.adj12.Visible = "on";
                app.adj13.Visible = "on";
                app.adj14.Visible = "on";
                app.adj15.Visible = "on";
                app.adj21.Visible = "on";
                app.adj22.Visible = "on";
                app.adj23.Visible = "on";
                app.adj24.Visible = "on";
                app.adj25.Visible = "on";
                app.adj31.Visible = "on";
                app.adj32.Visible = "on";
                app.adj33.Visible = "on";
                app.adj34.Visible = "on";
                app.adj35.Visible = "on";
                app.adj41.Visible = "on";
                app.adj42.Visible = "on";
                app.adj43.Visible = "on";
                app.adj44.Visible = "on";
                app.adj45.Visible = "on";
                app.adj51.Visible = "on";
                app.adj52.Visible = "on";
                app.adj53.Visible = "on";
                app.adj54.Visible = "on";
                app.adj55.Visible = "on";

                %inverse
                app.inv11.Visible = "on";
                app.inv12.Visible = "on";
                app.inv13.Visible = "on";
                app.inv14.Visible = "on";
                app.inv15.Visible = "on";
                app.inv21.Visible = "on";
                app.inv22.Visible = "on";
                app.inv23.Visible = "on";
                app.inv24.Visible = "on";
                app.inv25.Visible = "on";
                app.inv31.Visible = "on";
                app.inv32.Visible = "on";
                app.inv33.Visible = "on";
                app.inv34.Visible = "on";
                app.inv35.Visible = "on";
                app.inv41.Visible = "on";
                app.inv42.Visible = "on";
                app.inv43.Visible = "on";
                app.inv44.Visible = "on";
                app.inv45.Visible = "on";
                app.inv51.Visible = "on";
                app.inv52.Visible = "on";
                app.inv53.Visible = "on";
                app.inv54.Visible = "on";
                app.inv55.Visible = "on";

                %transpose
                app.trn11.Visible = "on";
                app.trn12.Visible = "on";
                app.trn13.Visible = "on";
                app.trn14.Visible = "on";
                app.trn15.Visible = "on";
                app.trn21.Visible = "on";
                app.trn22.Visible = "on";
                app.trn23.Visible = "on";
                app.trn24.Visible = "on";
                app.trn25.Visible = "on";
                app.trn31.Visible = "on";
                app.trn32.Visible = "on";
                app.trn33.Visible = "on";
                app.trn34.Visible = "on";
                app.trn35.Visible = "on";
                app.trn41.Visible = "on";
                app.trn42.Visible = "on";
                app.trn43.Visible = "on";
                app.trn44.Visible = "on";
                app.trn45.Visible = "on";
                app.trn51.Visible = "on";
                app.trn52.Visible = "on";
                app.trn53.Visible = "on";
                app.trn54.Visible = "on";
                app.trn55.Visible = "on";
            end

        end

        % Button pushed function: RowxColumnMatB
        function RowxColumnMatBPushed(app, event)
            RowB = app.RowSpinMatB.Value
            ColumnB = app.ColumnSpinMatB.Value
 
%Row1
            if RowB == 1 && ColumnB == 1
                    app.B11.Visible = "on";
                app.B12.Visible = "off";
                app.B13.Visible = "off";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                app.B21.Visible = "off";
                app.B22.Visible = "off";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                app.B31.Visible = "off";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 1 && ColumnB == 2
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                app.B13.Visible = "off";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                app.B21.Visible = "off";
                app.B22.Visible = "off";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                app.B31.Visible = "off";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 1 && ColumnB == 3
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                app.B21.Visible = "off";
                app.B22.Visible = "off";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                app.B31.Visible = "off";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 1 && ColumnB == 4
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                    app.B14.Visible = "on";
                app.B15.Visible = "off";
                app.B21.Visible = "off";
                app.B22.Visible = "off";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                app.B31.Visible = "off";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 1 && ColumnB == 5
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                    app.B14.Visible = "on";
                    app.B15.Visible = "on";
                app.B21.Visible = "off";
                app.B22.Visible = "off";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                app.B31.Visible = "off";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end
%Row2
            if RowB == 2 && ColumnB == 1
                    app.B11.Visible = "on";
                app.B12.Visible = "off";
                app.B13.Visible = "off";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                app.B22.Visible = "off";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                app.B31.Visible = "off";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 2 && ColumnB == 2
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                app.B13.Visible = "off";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                app.B31.Visible = "off";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 2 && ColumnB == 3
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                app.B31.Visible = "off";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 2 && ColumnB == 4
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                    app.B14.Visible = "on";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                    app.B24.Visible = "on";
                app.B25.Visible = "off";
                app.B31.Visible = "off";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 2 && ColumnB == 5
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                    app.B14.Visible = "on";
                    app.B15.Visible = "on";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                    app.B24.Visible = "on";
                    app.B25.Visible = "on";
                app.B31.Visible = "off";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end
%Row3
        	if RowB == 3 && ColumnB == 1
                    app.B11.Visible = "on";
                app.B12.Visible = "off";
                app.B13.Visible = "off";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                app.B22.Visible = "off";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 3 && ColumnB == 2
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                app.B13.Visible = "off";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 3 && ColumnB == 3
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                    app.B33.Visible = "on";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 3 && ColumnB == 4
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                    app.B14.Visible = "on";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                    app.B24.Visible = "on";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                    app.B33.Visible = "on";
                    app.B34.Visible = "on";
                app.B35.Visible = "off";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 3 && ColumnB == 5
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                    app.B14.Visible = "on";
                    app.B15.Visible = "on";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                    app.B24.Visible = "on";
                    app.B25.Visible = "on";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                    app.B33.Visible = "on";
                    app.B34.Visible = "on";
                    app.B35.Visible = "on";
                app.B41.Visible = "off";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end
%Row4
            if RowB == 4 && ColumnB == 1
                    app.B11.Visible = "on";
                app.B12.Visible = "off";
                app.B13.Visible = "off";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                app.B22.Visible = "off";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                    app.B41.Visible = "on";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 4 && ColumnB == 2
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                app.B13.Visible = "off";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                    app.B41.Visible = "on";
                    app.B42.Visible = "on";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 4 && ColumnB == 3
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                    app.B33.Visible = "on";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                    app.B41.Visible = "on";
                    app.B42.Visible = "on";
                    app.B43.Visible = "on";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 4 && ColumnB == 4
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                    app.B14.Visible = "on";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                    app.B24.Visible = "on";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                    app.B33.Visible = "on";
                    app.B34.Visible = "on";
                app.B35.Visible = "off";
                    app.B41.Visible = "on";
                    app.B42.Visible = "on";
                    app.B43.Visible = "on";
                    app.B44.Visible = "on";
                app.B45.Visible = "off";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 4 && ColumnB == 5
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                    app.B14.Visible = "on";
                    app.B15.Visible = "on";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                    app.B24.Visible = "on";
                    app.B25.Visible = "on";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                    app.B33.Visible = "on";
                    app.B34.Visible = "on";
                    app.B35.Visible = "on";
                    app.B41.Visible = "on";
                    app.B42.Visible = "on";
                    app.B43.Visible = "on";
                    app.B44.Visible = "on";
                    app.B45.Visible = "on";
                app.B51.Visible = "off";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end
%Row5
            if RowB == 5 && ColumnB == 1
                    app.B11.Visible = "on";
                app.B12.Visible = "off";
                app.B13.Visible = "off";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                app.B22.Visible = "off";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                app.B32.Visible = "off";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                    app.B41.Visible = "on";
                app.B42.Visible = "off";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                    app.B51.Visible = "on";
                app.B52.Visible = "off";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 5 && ColumnB == 2
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                app.B13.Visible = "off";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                app.B23.Visible = "off";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                app.B33.Visible = "off";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                    app.B41.Visible = "on";
                    app.B42.Visible = "on";
                app.B43.Visible = "off";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                    app.B51.Visible = "on";
                    app.B52.Visible = "on";
                app.B53.Visible = "off";
                app.B54.Visible = "off";
                app.B55.Visible = "off";
            end

            if RowB == 5 && ColumnB == 3
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                app.B14.Visible = "off";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                app.B24.Visible = "off";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                    app.B33.Visible = "on";
                app.B34.Visible = "off";
                app.B35.Visible = "off";
                    app.B41.Visible = "on";
                    app.B42.Visible = "on";
                    app.B43.Visible = "on";
                app.B44.Visible = "off";
                app.B45.Visible = "off";
                    app.B51.Visible = "on";
                    app.B52.Visible = "on";
                    app.B53.Visible = "on";
                app.B54.Visible = "off";
    %try
    app.B55.Visible = 'off';
            end

            if RowB == 5 && ColumnB == 4
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                    app.B14.Visible = "on";
                app.B15.Visible = "off";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                    app.B24.Visible = "on";
                app.B25.Visible = "off";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                    app.B33.Visible = "on";
                    app.B34.Visible = "on";
                app.B35.Visible = "off";
                    app.B41.Visible = "on";
                    app.B42.Visible = "on";
                    app.B43.Visible = "on";
                    app.B44.Visible = "on";
                app.B45.Visible = "off";
                    app.B51.Visible = "on";
                    app.B52.Visible = "on";
                    app.B53.Visible = "on";
                    app.B54.Visible = "on";
                app.B55.Visible = "off";
            end

            if RowB == 5 && ColumnB == 5
                    app.B11.Visible = "on";
                    app.B12.Visible = "on";
                    app.B13.Visible = "on";
                    app.B14.Visible = "on";
                    app.B15.Visible = "on";
                    app.B21.Visible = "on";
                    app.B22.Visible = "on";
                    app.B23.Visible = "on";
                    app.B24.Visible = "on";
                    app.B25.Visible = "on";
                    app.B31.Visible = "on";
                    app.B32.Visible = "on";
                    app.B33.Visible = "on";
                    app.B34.Visible = "on";
                    app.B35.Visible = "on";
                    app.B41.Visible = "on";
                    app.B42.Visible = "on";
                    app.B43.Visible = "on";
                    app.B44.Visible = "on";
                    app.B45.Visible = "on";
                    app.B51.Visible = "on";
                    app.B52.Visible = "on";
                    app.B53.Visible = "on";
                    app.B54.Visible = "on";
                    app.B55.Visible = "on";
            end
        end

        % Button pushed function: RowxColumnMatC
        function RowxColumnMatCButtonPushed(app, event)


        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            app111
            delete(tryy)
            delete(app.UIFigure)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            RowB = app.RowSpinMatB.Value;
            ColumnB = app.ColumnSpinMatB.Value;
            RowC = app.RowSpinMatC.Value
            ColumnC = app.ColumnSpinMatC.Value

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.A55 = uieditfield(app.Tab2, 'numeric');
            app.A55.Visible = 'off';
            app.A54 = uieditfield(app.Tab2, 'numeric');
            app.A54.Visible = 'off';
            app.A53 = uieditfield(app.Tab2, 'numeric');
            app.A53.Visible = 'off';
            app.A52 = uieditfield(app.Tab2, 'numeric');
            app.A52.Visible = 'off';
            app.A51 = uieditfield(app.Tab2, 'numeric');
            app.A51.Visible = 'off';
            app.A45 = uieditfield(app.Tab2, 'numeric');
            app.A45.Visible = 'off';
            app.A44 = uieditfield(app.Tab2, 'numeric');
            app.A44.Visible = 'off';
            app.A43 = uieditfield(app.Tab2, 'numeric');
            app.A43.Visible = 'off';
            app.A42 = uieditfield(app.Tab2, 'numeric');
            app.A42.Visible = 'off';
            app.A41 = uieditfield(app.Tab2, 'numeric');
            app.A41.Visible = 'off';
            app.A35 = uieditfield(app.Tab2, 'numeric');
            app.A35.Visible = 'off';
            app.A34 = uieditfield(app.Tab2, 'numeric');
            app.A34.Visible = 'off';
            app.A33 = uieditfield(app.Tab2, 'numeric');
            app.A33.Visible = 'off';
            app.A32 = uieditfield(app.Tab2, 'numeric');
            app.A32.Visible = 'off';
            app.A31 = uieditfield(app.Tab2, 'numeric');
            app.A31.Visible = 'off';
            app.A25 = uieditfield(app.Tab2, 'numeric');
            app.A25.Visible = 'off';
            app.A24 = uieditfield(app.Tab2, 'numeric');
            app.A24.Visible = 'off';
            app.A23 = uieditfield(app.Tab2, 'numeric');
            app.A23.Visible = 'off';
            app.A22 = uieditfield(app.Tab2, 'numeric');
            app.A21 = uieditfield(app.Tab2, 'numeric');
            app.A15 = uieditfield(app.Tab2, 'numeric');
            app.A15.Visible = 'off';
            app.A14 = uieditfield(app.Tab2, 'numeric');
            app.A14.Visible = 'off';
            app.A13 = uieditfield(app.Tab2, 'numeric');
            app.A13.Visible = 'off';
            app.A12 = uieditfield(app.Tab2, 'numeric');
            app.A11 = uieditfield(app.Tab2, 'numeric');
            app.ButtonGroup = uibuttongroup(app.Tab2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.x2Btn = uiradiobutton(app.ButtonGroup);
            app.x2Btn.Text = '2x2';
            app.x2Btn.Value = true;
            app.x3Btn = uiradiobutton(app.ButtonGroup);
            app.x3Btn.Text = '3x3';
            app.x4Btn = uiradiobutton(app.ButtonGroup);
            app.x4Btn.Text = '4x4';
            app.x5Btn = uiradiobutton(app.ButtonGroup);
            app.x5Btn.Text = '5x5';
            app.AdjointPanel = uipanel(app.Tab2);
            app.AdjointPanel.Title = 'Adjoint';
            app.adj55 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj55.ValueDisplayFormat = '%.0f';
            app.adj55.Editable = 'off';
            app.adj55.Visible = 'off';
            app.adj54 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj54.ValueDisplayFormat = '%.0f';
            app.adj54.Editable = 'off';
            app.adj54.Visible = 'off';
            app.adj53 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj53.ValueDisplayFormat = '%.0f';
            app.adj53.Editable = 'off';
            app.adj53.Visible = 'off';
            app.adj52 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj52.ValueDisplayFormat = '%.0f';
            app.adj52.Editable = 'off';
            app.adj52.Visible = 'off';
            app.adj51 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj51.ValueDisplayFormat = '%.0f';
            app.adj51.Editable = 'off';
            app.adj51.Visible = 'off';
            app.adj45 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj45.ValueDisplayFormat = '%.0f';
            app.adj45.Editable = 'off';
            app.adj45.Visible = 'off';
            app.adj44 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj44.ValueDisplayFormat = '%.0f';
            app.adj44.Editable = 'off';
            app.adj44.Visible = 'off';
            app.adj43 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj43.ValueDisplayFormat = '%.0f';
            app.adj43.Editable = 'off';
            app.adj43.Visible = 'off';
            app.adj42 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj42.ValueDisplayFormat = '%.0f';
            app.adj42.Editable = 'off';
            app.adj42.Visible = 'off';
            app.adj41 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj41.ValueDisplayFormat = '%.0f';
            app.adj41.Editable = 'off';
            app.adj41.Visible = 'off';
            app.adj35 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj35.ValueDisplayFormat = '%.0f';
            app.adj35.Editable = 'off';
            app.adj35.Visible = 'off';
            app.adj34 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj34.ValueDisplayFormat = '%.0f';
            app.adj34.Editable = 'off';
            app.adj34.Visible = 'off';
            app.adj33 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj33.ValueDisplayFormat = '%.0f';
            app.adj33.Editable = 'off';
            app.adj33.Visible = 'off';
            app.adj32 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj32.ValueDisplayFormat = '%.0f';
            app.adj32.Editable = 'off';
            app.adj32.Visible = 'off';
            app.adj31 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj31.ValueDisplayFormat = '%.0f';
            app.adj31.Editable = 'off';
            app.adj31.Visible = 'off';
            app.adj25 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj25.ValueDisplayFormat = '%.0f';
            app.adj25.Editable = 'off';
            app.adj25.Visible = 'off';
            app.adj24 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj24.ValueDisplayFormat = '%.0f';
            app.adj24.Editable = 'off';
            app.adj24.Visible = 'off';
            app.adj23 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj23.ValueDisplayFormat = '%.0f';
            app.adj23.Editable = 'off';
            app.adj23.Visible = 'off';
            app.adj22 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj22.ValueDisplayFormat = '%.0f';
            app.adj22.Editable = 'off';
            app.adj21 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj21.ValueDisplayFormat = '%.0f';
            app.adj21.Editable = 'off';
            app.adj15 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj15.ValueDisplayFormat = '%.0f';
            app.adj15.Editable = 'off';
            app.adj15.Visible = 'off';
            app.adj14 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj14.ValueDisplayFormat = '%.0f';
            app.adj14.Editable = 'off';
            app.adj14.Visible = 'off';
            app.adj13 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj13.ValueDisplayFormat = '%.0f';
            app.adj13.Editable = 'off';
            app.adj13.Visible = 'off';
            app.adj12 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj12.ValueDisplayFormat = '%.0f';
            app.adj12.Editable = 'off';
            app.adj11 = uieditfield(app.AdjointPanel, 'numeric');
            app.adj11.ValueDisplayFormat = '%.0f';
            app.adj11.Editable = 'off';
            app.InverseMatrixPanel = uipanel(app.Tab2);
            app.InverseMatrixPanel.Title = 'Inverse Matrix';
            app.inv55 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv55.ValueDisplayFormat = '%.2f';
            app.inv55.Editable = 'off';
            app.inv55.Visible = 'off';
            app.inv54 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv54.ValueDisplayFormat = '%.2f';
            app.inv54.Editable = 'off';
            app.inv54.Visible = 'off';
            app.inv53 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv53.ValueDisplayFormat = '%.2f';
            app.inv53.Editable = 'off';
            app.inv53.Visible = 'off';
            app.inv52 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv52.ValueDisplayFormat = '%.2f';
            app.inv52.Editable = 'off';
            app.inv52.Visible = 'off';
            app.inv51 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv51.ValueDisplayFormat = '%.2f';
            app.inv51.Editable = 'off';
            app.inv51.Visible = 'off';
            app.inv45 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv45.ValueDisplayFormat = '%.2f';
            app.inv45.Editable = 'off';
            app.inv45.Visible = 'off';
            app.inv44 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv44.ValueDisplayFormat = '%.2f';
            app.inv44.Editable = 'off';
            app.inv44.Visible = 'off';
            app.inv43 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv43.ValueDisplayFormat = '%.2f';
            app.inv43.Editable = 'off';
            app.inv43.Visible = 'off';
            app.inv42 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv42.ValueDisplayFormat = '%.2f';
            app.inv42.Editable = 'off';
            app.inv42.Visible = 'off';
            app.inv41 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv41.ValueDisplayFormat = '%.2f';
            app.inv41.Editable = 'off';
            app.inv41.Visible = 'off';
            app.inv35 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv35.ValueDisplayFormat = '%.2f';
            app.inv35.Editable = 'off';
            app.inv35.Visible = 'off';
            app.inv34 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv34.ValueDisplayFormat = '%.2f';
            app.inv34.Editable = 'off';
            app.inv34.Visible = 'off';
            app.inv33 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv33.ValueDisplayFormat = '%.2f';
            app.inv33.Editable = 'off';
            app.inv33.Visible = 'off';
            app.inv32 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv32.ValueDisplayFormat = '%.2f';
            app.inv32.Editable = 'off';
            app.inv32.Visible = 'off';
            app.inv31 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv31.ValueDisplayFormat = '%.2f';
            app.inv31.Editable = 'off';
            app.inv31.Visible = 'off';
            app.inv25 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv25.ValueDisplayFormat = '%.2f';
            app.inv25.Editable = 'off';
            app.inv25.Visible = 'off';
            app.inv24 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv24.ValueDisplayFormat = '%.2f';
            app.inv24.Editable = 'off';
            app.inv24.Visible = 'off';
            app.inv23 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv23.ValueDisplayFormat = '%.2f';
            app.inv23.Editable = 'off';
            app.inv23.Visible = 'off';
            app.inv22 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv22.ValueDisplayFormat = '%.2f';
            app.inv22.Editable = 'off';
            app.inv21 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv21.ValueDisplayFormat = '%.2f';
            app.inv21.Editable = 'off';
            app.inv15 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv15.ValueDisplayFormat = '%.2f';
            app.inv15.Editable = 'off';
            app.inv15.Visible = 'off';
            app.inv14 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv14.ValueDisplayFormat = '%.2f';
            app.inv14.Editable = 'off';
            app.inv14.Visible = 'off';
            app.inv13 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv13.ValueDisplayFormat = '%.2f';
            app.inv13.Editable = 'off';
            app.inv13.Visible = 'off';
            app.inv12 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv12.ValueDisplayFormat = '%.2f';
            app.inv12.Editable = 'off';
            app.inv11 = uieditfield(app.InverseMatrixPanel, 'numeric');
            app.inv11.ValueDisplayFormat = '%.2f';
            app.inv11.Editable = 'off';
            app.TransposePanel = uipanel(app.Tab2);
            app.TransposePanel.Title = 'Transpose';
            app.trn55 = uieditfield(app.TransposePanel, 'numeric');
            app.trn55.ValueDisplayFormat = '%.0f';
            app.trn55.Editable = 'off';
            app.trn55.Visible = 'off';
            app.trn54 = uieditfield(app.TransposePanel, 'numeric');
            app.trn54.ValueDisplayFormat = '%.0f';
            app.trn54.Editable = 'off';
            app.trn54.Visible = 'off';
            app.trn53 = uieditfield(app.TransposePanel, 'numeric');
            app.trn53.ValueDisplayFormat = '%.0f';
            app.trn53.Editable = 'off';
            app.trn53.Visible = 'off';
            app.trn52 = uieditfield(app.TransposePanel, 'numeric');
            app.trn52.ValueDisplayFormat = '%.0f';
            app.trn52.Editable = 'off';
            app.trn52.Visible = 'off';
            app.trn51 = uieditfield(app.TransposePanel, 'numeric');
            app.trn51.ValueDisplayFormat = '%.0f';
            app.trn51.Editable = 'off';
            app.trn51.Visible = 'off';
            app.trn45 = uieditfield(app.TransposePanel, 'numeric');
            app.trn45.ValueDisplayFormat = '%.0f';
            app.trn45.Editable = 'off';
            app.trn45.Visible = 'off';
            app.trn44 = uieditfield(app.TransposePanel, 'numeric');
            app.trn44.ValueDisplayFormat = '%.0f';
            app.trn44.Editable = 'off';
            app.trn44.Visible = 'off';
            app.trn43 = uieditfield(app.TransposePanel, 'numeric');
            app.trn43.ValueDisplayFormat = '%.0f';
            app.trn43.Editable = 'off';
            app.trn43.Visible = 'off';
            app.trn42 = uieditfield(app.TransposePanel, 'numeric');
            app.trn42.ValueDisplayFormat = '%.0f';
            app.trn42.Editable = 'off';
            app.trn42.Visible = 'off';
            app.trn41 = uieditfield(app.TransposePanel, 'numeric');
            app.trn41.ValueDisplayFormat = '%.0f';
            app.trn41.Editable = 'off';
            app.trn41.Visible = 'off';
            app.trn35 = uieditfield(app.TransposePanel, 'numeric');
            app.trn35.ValueDisplayFormat = '%.0f';
            app.trn35.Editable = 'off';
            app.trn35.Visible = 'off';
            app.trn34 = uieditfield(app.TransposePanel, 'numeric');
            app.trn34.ValueDisplayFormat = '%.0f';
            app.trn34.Editable = 'off';
            app.trn34.Visible = 'off';
            app.trn33 = uieditfield(app.TransposePanel, 'numeric');
            app.trn33.ValueDisplayFormat = '%.0f';
            app.trn33.Editable = 'off';
            app.trn33.Visible = 'off';
            app.trn32 = uieditfield(app.TransposePanel, 'numeric');
            app.trn32.ValueDisplayFormat = '%.0f';
            app.trn32.Editable = 'off';
            app.trn32.Visible = 'off';
            app.trn31 = uieditfield(app.TransposePanel, 'numeric');
            app.trn31.ValueDisplayFormat = '%.0f';
            app.trn31.Editable = 'off';
            app.trn31.Visible = 'off';
            app.trn25 = uieditfield(app.TransposePanel, 'numeric');
            app.trn25.ValueDisplayFormat = '%.0f';
            app.trn25.Editable = 'off';
            app.trn25.Visible = 'off';
            app.trn24 = uieditfield(app.TransposePanel, 'numeric');
            app.trn24.ValueDisplayFormat = '%.0f';
            app.trn24.Editable = 'off';
            app.trn24.Visible = 'off';
            app.trn23 = uieditfield(app.TransposePanel, 'numeric');
            app.trn23.ValueDisplayFormat = '%.0f';
            app.trn23.Editable = 'off';
            app.trn23.Visible = 'off';
            app.trn22 = uieditfield(app.TransposePanel, 'numeric');
            app.trn22.ValueDisplayFormat = '%.0f';
            app.trn22.Editable = 'off';
            app.trn21 = uieditfield(app.TransposePanel, 'numeric');
            app.trn21.ValueDisplayFormat = '%.0f';
            app.trn21.Editable = 'off';
            app.trn15 = uieditfield(app.TransposePanel, 'numeric');
            app.trn15.ValueDisplayFormat = '%.0f';
            app.trn15.Editable = 'off';
            app.trn15.Visible = 'off';
            app.trn14 = uieditfield(app.TransposePanel, 'numeric');
            app.trn14.ValueDisplayFormat = '%.0f';
            app.trn14.Editable = 'off';
            app.trn14.Visible = 'off';
            app.trn13 = uieditfield(app.TransposePanel, 'numeric');
            app.trn13.ValueDisplayFormat = '%.0f';
            app.trn13.Editable = 'off';
            app.trn13.Visible = 'off';
            app.trn12 = uieditfield(app.TransposePanel, 'numeric');
            app.trn12.ValueDisplayFormat = '%.0f';
            app.trn12.Editable = 'off';
            app.trn11 = uieditfield(app.TransposePanel, 'numeric');
            app.trn11.ValueDisplayFormat = '%.0f';
            app.trn11.Editable = 'off';
            app.EditField = uieditfield(app.Tab2, 'numeric');
            app.EditField.ValueDisplayFormat = '%.2f';
            app.detbuttonButton = uibutton(app.Tab2, 'push');
            app.detbuttonButton.ButtonPushedFcn = createCallbackFcn(app, @detbuttonButtonPushed, true);
            app.detbuttonButton.Text = 'det button';
            app.nextButton = uibutton(app.Tab2, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.Button2 = uibutton(app.Tab2, 'push');
            app.Button2.Text = 'Button2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.AddButton = uibutton(app.Tab3, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.MATRIXDPanel = uipanel(app.Tab3);
            app.MATRIXDPanel.Title = 'MATRIX D';
            app.D55 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D55.Visible = 'off';
            app.D54 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D54.Visible = 'off';
            app.D53 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D53.Visible = 'off';
            app.D52 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D52.Visible = 'off';
            app.D51 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D51.Visible = 'off';
            app.D45 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D45.Visible = 'off';
            app.D44 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D44.Visible = 'off';
            app.D43 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D43.Visible = 'off';
            app.D42 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D42.Visible = 'off';
            app.D41 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D41.Visible = 'off';
            app.D35 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D35.Visible = 'off';
            app.D34 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D34.Visible = 'off';
            app.D33 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D33.Visible = 'off';
            app.D32 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D32.Visible = 'off';
            app.D31 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D31.Visible = 'off';
            app.D25 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D25.Visible = 'off';
            app.D24 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D24.Visible = 'off';
            app.D23 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D23.Visible = 'off';
            app.D22 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D22.Visible = 'off';
            app.D21 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D21.Visible = 'off';
            app.D15 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D15.Visible = 'off';
            app.D14 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D14.Visible = 'off';
            app.D13 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D13.Visible = 'off';
            app.D12 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D12.Visible = 'off';
            app.D11 = uieditfield(app.MATRIXDPanel, 'numeric');
            app.D11.Visible = 'off';
            app.MATRIXCPanel = uipanel(app.Tab3);
            app.MATRIXCPanel.Title = 'MATRIX C';
            app.C55 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C55.Visible = 'off';
            app.C54 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C54.Visible = 'off';
            app.C53 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C53.Visible = 'off';
            app.C52 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C52.Visible = 'off';
            app.C51 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C51.Visible = 'off';
            app.C45 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C45.Visible = 'off';
            app.C44 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C44.Visible = 'off';
            app.C43 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C43.Visible = 'off';
            app.C42 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C42.Visible = 'off';
            app.C41 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C41.Visible = 'off';
            app.C35 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C35.Visible = 'off';
            app.C34 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C34.Visible = 'off';
            app.C33 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C33.Visible = 'off';
            app.C32 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C32.Visible = 'off';
            app.C31 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C31.Visible = 'off';
            app.C25 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C25.Visible = 'off';
            app.C24 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C24.Visible = 'off';
            app.C23 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C23.Visible = 'off';
            app.C22 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C22.Visible = 'off';
            app.C21 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C21.Visible = 'off';
            app.C15 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C15.Visible = 'off';
            app.C14 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C14.Visible = 'off';
            app.C13 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C13.Visible = 'off';
            app.C12 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C12.Visible = 'off';
            app.C11 = uieditfield(app.MATRIXCPanel, 'numeric');
            app.C11.Visible = 'off';
            app.RowSpinnerLabel_2 = uilabel(app.MATRIXCPanel);
            app.RowSpinnerLabel_2.Text = 'Row';
            app.RowSpinMatC = uispinner(app.MATRIXCPanel);
            app.RowSpinMatC.Limits = [1 5];
            app.RowSpinMatC.Value = 1;
            app.ColumnLabel_2 = uilabel(app.MATRIXCPanel);
            app.ColumnLabel_2.Text = 'Column';
            app.ColumnSpinMatC = uispinner(app.MATRIXCPanel);
            app.ColumnSpinMatC.Limits = [1 5];
            app.ColumnSpinMatC.Value = 1;
            app.RowxColumnMatC = uibutton(app.MATRIXCPanel, 'push');
            app.RowxColumnMatC.ButtonPushedFcn = createCallbackFcn(app, @RowxColumnMatCButtonPushed, true);
            app.RowxColumnMatC.Text = 'Row x Column';
            app.MATRIXBPanel = uipanel(app.Tab3);
            app.MATRIXBPanel.Title = 'MATRIX B';
            app.B55 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B55.Visible = 'off';
            app.B54 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B54.Visible = 'off';
            app.B53 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B53.Visible = 'off';
            app.B52 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B52.Visible = 'off';
            app.B51 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B51.Visible = 'off';
            app.B45 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B45.Visible = 'off';
            app.B44 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B44.Visible = 'off';
            app.B43 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B43.Visible = 'off';
            app.B42 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B42.Visible = 'off';
            app.B41 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B41.Visible = 'off';
            app.B35 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B35.Visible = 'off';
            app.B34 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B34.Visible = 'off';
            app.B33 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B33.Visible = 'off';
            app.B32 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B32.Visible = 'off';
            app.B31 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B31.Visible = 'off';
            app.B25 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B25.Visible = 'off';
            app.B24 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B24.Visible = 'off';
            app.B23 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B23.Visible = 'off';
            app.B22 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B22.Visible = 'off';
            app.B21 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B21.Visible = 'off';
            app.B15 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B15.Visible = 'off';
            app.B14 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B14.Visible = 'off';
            app.B13 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B13.Visible = 'off';
            app.B12 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B12.Visible = 'off';
            app.B11 = uieditfield(app.MATRIXBPanel, 'numeric');
            app.B11.Visible = 'off';
            app.RowSpinnerLabel = uilabel(app.MATRIXBPanel);
            app.RowSpinnerLabel.Text = 'Row';
            app.RowSpinMatB = uispinner(app.MATRIXBPanel);
            app.RowSpinMatB.Limits = [1 5];
            app.RowSpinMatB.Value = 1;
            app.ColumnLabel = uilabel(app.MATRIXBPanel);
            app.ColumnLabel.Text = 'Column';
            app.ColumnSpinMatB = uispinner(app.MATRIXBPanel);
            app.ColumnSpinMatB.Limits = [1 5];
            app.ColumnSpinMatB.Value = 1;
            app.RowxColumnMatB = uibutton(app.MATRIXBPanel, 'push');
            app.RowxColumnMatB.ButtonPushedFcn = createCallbackFcn(app, @RowxColumnMatBPushed, true);
            app.RowxColumnMatB.Text = 'Row x Column';
            app.AddButton_2 = uibutton(app.Tab3, 'push');
            app.AddButton_2.Text = 'Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tryy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end